classdef SPRSoftwarev1p1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        AboutPRISMAMenu           matlab.ui.container.Menu
        Changelog                 matlab.ui.container.Menu
        Toolbar                   matlab.ui.container.Toolbar
        SaveSession               matlab.ui.container.toolbar.PushTool
        LoadSession               matlab.ui.container.toolbar.PushTool
        ExportTool                matlab.ui.container.toolbar.PushTool
        CopyGraph                 matlab.ui.container.toolbar.ToggleTool
        DarkModeTool              matlab.ui.container.toolbar.PushTool
        ResetTool                 matlab.ui.container.toolbar.PushTool
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        StatusLamp                matlab.ui.control.Lamp
        Panel                     matlab.ui.container.Panel
        SimulationPanel           matlab.ui.container.Panel
        Label                     matlab.ui.control.Label
        AngleMaxEditField         matlab.ui.control.NumericEditField
        AngleRangeCheckBox        matlab.ui.control.CheckBox
        AngleMinEditField         matlab.ui.control.NumericEditField
        Fornkandd1LowerLimit2UpperLimitLabel  matlab.ui.control.Label
        FinerLabel_3              matlab.ui.control.Label
        CoarserLabel_2            matlab.ui.control.Label
        FinerLabel                matlab.ui.control.Label
        CoarserLabel              matlab.ui.control.Label
        FittingModeLabel          matlab.ui.control.Label
        FittingModeButtonGroup    matlab.ui.container.ButtonGroup
        AutoButton                matlab.ui.control.RadioButton
        ManualButton              matlab.ui.control.RadioButton
        OffButton                 matlab.ui.control.RadioButton
        ResetRangesButton         matlab.ui.control.Button
        AutofitValueLabel         matlab.ui.control.Label
        AutofitRunButton          matlab.ui.control.Button
        dSlider                   matlab.ui.control.Slider
        nmSliderLabel             matlab.ui.control.Label
        dRightButton              matlab.ui.control.Button
        dLeftButton               matlab.ui.control.Button
        kSlider                   matlab.ui.control.Slider
        kLabel                    matlab.ui.control.Label
        kRightButton              matlab.ui.control.Button
        kLeftButton               matlab.ui.control.Button
        nSlider                   matlab.ui.control.Slider
        SliderLabel               matlab.ui.control.Label
        LayerSelectionDropDown    matlab.ui.control.DropDown
        ButtonTuningSlider        matlab.ui.control.Slider
        ButtonTuningLabel         matlab.ui.control.Label
        AutofitSlider             matlab.ui.control.Slider
        AutofitLabel              matlab.ui.control.Label
        nRightButton              matlab.ui.control.Button
        nLeftButton               matlab.ui.control.Button
        LayerParamUITable         matlab.ui.control.Table
        StatusTextArea            matlab.ui.control.TextArea
        SetupPanel                matlab.ui.container.Panel
        PrismAngleEditField       matlab.ui.control.NumericEditField
        PrismAngleEditFieldLabel  matlab.ui.control.Label
        PrismTypeDropDown         matlab.ui.control.DropDown
        PrismtypeDropDownLabel    matlab.ui.control.Label
        LambdaEditField           matlab.ui.control.NumericEditField
        nmLabel                   matlab.ui.control.Label
        LoadDataTextArea          matlab.ui.control.TextArea
        LoaddataButton            matlab.ui.control.Button
        NumOfLayersSpinner        matlab.ui.control.Spinner
        NooflayersSpinnerLabel    matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        RightPanelGridLayout      matlab.ui.container.GridLayout
        TabGroup                  matlab.ui.container.TabGroup
        MainTab                   matlab.ui.container.Tab
        MainTabGridLayout         matlab.ui.container.GridLayout
        InfoPanel                 matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        GreenGoodFitLabel         matlab.ui.control.Label
        AmberAcceptableFitLabel   matlab.ui.control.Label
        RedBadFitLabel            matlab.ui.control.Label
        nkdvalueTextArea          matlab.ui.control.TextArea
        nkdvalueTextAreaLabel     matlab.ui.control.Label
        FWHMPanel                 matlab.ui.container.Panel
        FWHMLabel                 matlab.ui.control.Label
        FWHMValueLabel            matlab.ui.control.Label
        ThetaPanel                matlab.ui.container.Panel
        ThetaLabel                matlab.ui.control.Label
        ThetaValueLabel           matlab.ui.control.Label
        R2Panel                   matlab.ui.container.Panel
        R2Label                   matlab.ui.control.Label
        R2ValueLabel              matlab.ui.control.Label
        RMSEPanel                 matlab.ui.container.Panel
        RMSELabel                 matlab.ui.control.Label
        RMSEValueLabel            matlab.ui.control.Label
        PlasmaResonanceIntegratedSoftwarewithModelAutofittingPRISMLabel  matlab.ui.control.Label
        AppNameLabel              matlab.ui.control.Label
        MainUIAxes                matlab.ui.control.UIAxes
        DataTab                   matlab.ui.container.Tab
        PointsPerDegree           matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel  matlab.ui.control.Label
        ResultsTable              matlab.ui.control.Table
        InterpCheckBox            matlab.ui.control.CheckBox
        InterpPointsSpinner       matlab.ui.control.Spinner
        NoOfDataSpinnerLabel      matlab.ui.control.Label
        ReferencesTab             matlab.ui.container.Tab
        TohelpfindthenandkvaluesofmaterialsTextArea  matlab.ui.control.TextArea
        TohelpfindthenandkvaluesofmaterialsTextAreaLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
        properties (Access = public)
            LayerList
            Lambda = 632.8e-9 
            PrismDegree = 60 % (!) Still working on this
            AutofitTuning = 50
            DataPath
            ParamRanges = {[0.001 3], [0 5], [0 100]}
            Layers = cell(10, 9)
            UsedLayers
            SelectedLayer
            ButtonTuningSize
            ParamTuningSize
            FittingMode
            ExperimentalData
            DefaultStatus = 'App is ready.'
            ScatterDataHndl
            LineSimulationHndl
            IsDataLoaded = false
            LayersTabExtended;
            ThetaRange
            SelectedCell
            ParamLetters = ["n", "k", "d"]
            ColumnIdx = [2, 5, 8]
            AppVersion = '1.1'
            AppShortName = 'PRISMA'
            AppFullName = 'Plasma Resonance Integrated Software with Model Auto-Fitting'
            SimHndl
            SimulatedR              double
            SimulatedTheta          double
            RawThetaRange           double
            RawData                 double
            AngleMin    double
            AngleMax    double
            InterpolatedData    double
            NumSimPoints    double
            SmoothSimTheta      double
            SmoothSimR          double
            ConfidenceIntervals     struct
            IsDarkMode      logical
            PointsPerDegreeNumeric double = 20
        end
    
    
        methods (Access = private)
    
            function LayerList = listLayers(app)
                LayerList = "Layer " + string(1:app.NumOfLayersSpinner.Value);
            end
    
            function applyTheme(app)
                if app.IsDarkMode
                    % Dark theme colors
                    BG          = [0.12 0.12 0.14];
                    PanelBG     = [0.16 0.16 0.18];
                    ControlBG   = [0.22 0.22 0.26];
                    TextColor   = [0.92 0.92 0.92];
                    AxesBG      = [0.14 0.14 0.16];
                    GridColor   = [0.35 0.35 0.40];
                    BorderColor = [0.30 0.30 0.35];
                    ToolbarBG   = [0.08 0.08 0.10];
                    PlotLine    = [0.40 0.70 1.00];
                    StatusBG    = [0.18 0.18 0.20];
                else
                    % Light theme colors
                    BG          = [0.94 0.94 0.92];
                    PanelBG     = [1.00 1.00 1.00];
                    ControlBG   = [0.96 0.96 0.94];
                    TextColor   = [0.10 0.10 0.10];
                    AxesBG      = [0.94 0.94 0.94];
                    GridColor   = [0.00 0.00 1.00];
                    BorderColor = [0.89 0.89 0.86];
                    ToolbarBG   = [0.02 0.20 0.29];
                    PlotLine    = [0.10 0.23 0.36];
                    StatusBG    = [1.00 1.00 1.00];
                end
    
                % UIFigure
                app.UIFigure.Color = BG;
    
                % Toolbar
                app.Toolbar.BackgroundColor = ToolbarBG;
    
                % Panels
                app.LeftPanel.BackgroundColor          = PanelBG;
                app.SetupPanel.BackgroundColor         = PanelBG;
                app.SimulationPanel.BackgroundColor    = PanelBG;
                app.InfoPanel.BackgroundColor          = PanelBG;
                app.GridLayout3.BackgroundColor        = PanelBG;
                app.MainTabGridLayout.BackgroundColor  = PanelBG;
                app.RightPanel.BackgroundColor         = PanelBG;
    
                % Tab backgrounds
                app.MainTab.BackgroundColor = PanelBG;
                app.DataTab.BackgroundColor = PanelBG;
    
                % Status area
                app.StatusTextArea.BackgroundColor = StatusBG;
                app.StatusTextArea.FontColor       = TextColor;
    
                % Axes
                app.MainUIAxes.Color      = AxesBG;
                app.MainUIAxes.XColor     = TextColor;
                app.MainUIAxes.YColor     = TextColor;
                app.MainUIAxes.GridColor  = GridColor;
                app.MainUIAxes.GridAlpha  = 0.15;
    
                % FWHM and resonance lines on axes
                FWHMLine = findobj(app.MainUIAxes, 'Tag', 'FWHMLine');
                ResonanceLine = findobj(app.MainUIAxes, 'Tag', 'ResonanceLine');
    
                if app.IsDarkMode
                    LineCol  = [1 1 1];
                    ResonanceCol = [1 0.4 0.4];
                else
                    LineCol  = [0 0 0];
                    ResonanceCol = [1 0 0];
                end
    
                if ~isempty(FWHMLine)
                    FWHMLine.Color     = LineCol;
                    FWHMLine.LabelColor = LineCol;
                end
    
                if ~isempty(ResonanceLine)
                    ResonanceLine.Color      = ResonanceCol;
                    ResonanceLine.LabelColor = ResonanceCol;
                end
    
                % Labels — left panel
                AllLabels = [app.NooflayersSpinnerLabel, app.nmLabel, ...
                    app.PrismtypeDropDownLabel, app.PrismAngleEditFieldLabel, ...
                    app.FittingModeLabel, app.SliderLabel, app.kLabel, ...
                    app.nmSliderLabel, app.ButtonTuningLabel, ...
                    app.CoarserLabel, app.FinerLabel, app.CoarserLabel_2, ...
                    app.FinerLabel_3, app.AutofitLabel, app.AutofitValueLabel, ...
                    app.nkdvalueTextAreaLabel, app.Fornkandd1LowerLimit2UpperLimitLabel, ...
                    app.AppNameLabel, app.AngleRangeCheckBox];
                for lbl = AllLabels
                    lbl.FontColor = TextColor;
                end
    
                % nkd text area
                app.nkdvalueTextArea.BackgroundColor = ControlBG;
                app.nkdvalueTextArea.FontColor       = TextColor;
    
                % Load data text area
                app.LoadDataTextArea.BackgroundColor = ControlBG;
                app.LoadDataTextArea.FontColor       = TextColor;
    
    
                % DataTab background
                app.DataTab.BackgroundColor = PanelBG;
    
                % Results table
                app.ResultsTable.BackgroundColor = ControlBG;
                app.ResultsTable.ForegroundColor = TextColor;

    
                % Interp controls
                app.InterpCheckBox.FontColor          = TextColor;
                app.NoOfDataSpinnerLabel.FontColor    = TextColor;
                app.InterpPointsSpinner.BackgroundColor = ControlBG;
                app.InterpPointsSpinner.FontColor       = TextColor;
    
                % Edit fields
                EditFields = [app.LambdaEditField, app.PrismAngleEditField, ...
                    app.AngleMinEditField, app.AngleMaxEditField];
                for ef = EditFields
                    ef.BackgroundColor = ControlBG;
                    ef.FontColor       = TextColor;
                end
    
                % Dropdowns
                app.PrismTypeDropDown.BackgroundColor      = ControlBG;
                app.PrismTypeDropDown.FontColor            = TextColor;
                app.LayerSelectionDropDown.BackgroundColor = ControlBG;
                app.LayerSelectionDropDown.FontColor       = TextColor;
    
                % Spinners
                app.NumOfLayersSpinner.BackgroundColor    = ControlBG;
                app.NumOfLayersSpinner.FontColor          = TextColor;
                app.InterpPointsSpinner.BackgroundColor   = ControlBG;
                app.InterpPointsSpinner.FontColor         = TextColor;
    
                % Table
                app.LayerParamUITable.BackgroundColor = ControlBG;
                app.LayerParamUITable.ForegroundColor = TextColor;
                app.ResultsTable.BackgroundColor      = ControlBG;
                app.ResultsTable.ForegroundColor      = TextColor;
    
                % Sliders font color
                app.nSlider.FontColor          = TextColor;
                app.kSlider.FontColor          = TextColor;
                app.dSlider.FontColor          = TextColor;
                app.AutofitSlider.FontColor    = TextColor;
                app.ButtonTuningSlider.FontColor = TextColor;
    
                % Simulation plot line color
                if ~isempty(app.SimHndl) && isvalid(app.SimHndl)
                    app.SimHndl.Color = PlotLine;
                end
    
                % Update toolbar button icon text to reflect current mode
                if app.IsDarkMode
                    app.DarkModeTool.Tooltip = {'Switch to Light Mode'};
                else
                    app.DarkModeTool.Tooltip = {'Switch to Dark Mode'};
                end
            end
    
            function selectDataColumns(app)
                NumCols = size(app.RawData, 2);
                ColOptions = "Column " + string(1:NumCols);
    
                d = uifigure('Name', 'Select Columns', ...
                    'Position', [500 400 300 200], ...
                    'WindowStyle', 'modal');
    
                uilabel(d, 'Text', 'X-axis (Angle):', ...
                    'Position', [20 130 120 22]);
                XDropDown = uidropdown(d, 'Items', ColOptions, ...
                    'Position', [150 130 120 22], ...
                    'Value', ColOptions(1));
    
                uilabel(d, 'Text', 'Y-axis (Reflectance):', ...
                    'Position', [20 90 130 22]);
                YDropDown = uidropdown(d, 'Items', ColOptions, ...
                    'Position', [150 90 120 22], ...
                    'Value', ColOptions(min(2, NumCols)));
    
                uibutton(d, 'Text', 'OK', ...
                    'Position', [100 40 100 30], ...
                    'ButtonPushedFcn', @(~,~) confirmSelection());
    
                uiwait(d);
    
                function confirmSelection()
                    XCol = find(ColOptions == XDropDown.Value);
                    YCol = find(ColOptions == YDropDown.Value);
                    if XCol == YCol
                        uialert(d, 'X and Y must be different columns.', 'Invalid Selection');
                        return;
                    end
                    app.ExperimentalData = app.RawData(:, [XCol, YCol]);
                    uiresume(d);
                    delete(d);
                end
            end
    
            
            function applyInterpolation(app)
                if ~app.IsDataLoaded
                    return;
                end
    
                OrigTheta = app.ExperimentalData(:, 1);
                OrigR     = app.ExperimentalData(:, 2);
                NumPts    = app.InterpPointsSpinner.Value;
    
                % Create new evenly spaced angle vector
                NewTheta = linspace(OrigTheta(1), OrigTheta(end), NumPts)';
    
                % Interpolate reflectance at new angles
                NewR = interp1(OrigTheta, OrigR, NewTheta, 'linear');
    
                % Store interpolated data
                app.InterpolatedData = [NewTheta, NewR];
    
                % Update ThetaRange to use interpolated angles
                app.ThetaRange = NewTheta;
    
                % Re-plot scatter with interpolated data
                delete(app.ScatterDataHndl);
                hold(app.MainUIAxes, 'on');
                app.ScatterDataHndl = scatter(app.MainUIAxes, NewTheta, NewR, 18, ...
                    'MarkerEdgeColor', [0.75 0.22 0.17], ...
                    'MarkerFaceColor', 'none', ...
                    'DisplayName', 'Experimental data (interp.)');
    
                % Update angle range fields
                app.AngleMin = NewTheta(1);
                app.AngleMax = NewTheta(end);
                if app.AngleRangeCheckBox.Value
                    app.AngleMinEditField.Value = app.AngleMin;
                    app.AngleMaxEditField.Value = app.AngleMax;
                end
    
                updateStatus(app, sprintf('Data interpolated to %d points (%.2f° – %.2f°).', ...
                    NumPts, NewTheta(1), NewTheta(end)));
            end
    
            function updateParamTable(app)
                app.UsedLayers = app.Layers(1:app.NumOfLayersSpinner.Value, :);
                app.LayerParamUITable.Data = app.UsedLayers;
            end
    
          function setInitialSlider(app, SetRange, SetValue)
                if SetRange
                    [app.nSlider.Limits, app.kSlider.Limits, app.dSlider.Limits] = deal(app.ParamRanges{:});
                    
                    % --- BEAUTIFIED TICKS (Work in Progress) ---
                    minLimit = app.nSlider.Limits(1);
                    maxLimit = app.nSlider.Limits(2);
                    
                    % 1. Generate clean whole numbers instead of weird fractions (e.g., 1, 2, 3)
                    cleanIntegerTicks = ceil(minLimit) : 1 : floor(maxLimit);
                    
                    % 2. Combine the outer limits, the integers, and our target materials
                    % TODO: Add more specific material indices here later (e.g., 1.45 for Silica glass)
                    customTicks = [minLimit, cleanIntegerTicks, 1.0, 1.33, maxLimit];
                    
                    % 3. Round slightly to prevent floating-point messiness, remove duplicates, and sort
                    % Using 3 decimal places preserves your 0.001 minimum
                    customTicks = sort(unique(round(customTicks, 3)));
                    
                    % 4. Final safety filter
                    validTicks = customTicks(customTicks >= minLimit & customTicks <= maxLimit);
                    
                    app.nSlider.MajorTicks = validTicks;
                end
                
                if SetValue
                    [app.Layers{:, 2}, app.nSlider.Value]  = deal(min(app.nSlider.Limits));
                    [app.Layers{:, 5}, app.kSlider.Value]  = deal(min(app.kSlider.Limits));
                    [app.Layers{:, 8}, app.dSlider.Value]  = deal(min(app.dSlider.Limits));
                end
            end
    
            function extendSliderRange(app, SliderValue, SliderHandle, Increment)
                if SliderValue >= SliderHandle.Limits(2)
                    SliderHandle.Limits(2) = SliderHandle.Limits(2) + Increment;
                    set(app.ResetRangesButton, Visible = 'on', Enable = 'on');
                end
            end
    
            function updateStatus(app, StatusText)
                app.StatusTextArea.Value = StatusText;
    
                % Stop and delete any existing status timer
                OldTimers = timerfind('Name', 'StatusTimer');
                if ~isempty(OldTimers)
                    stop(OldTimers);
                    delete(OldTimers);
                end
    
                % Create new timer
                StatusTimer = timer(...
                    Name = 'StatusTimer', ...
                    StartDelay = 8, ...
                    TimerFcn = @(~, ~) resetStatus(app));
            end
    
            function ScatterDataHndl = plotExperimentalData(app)
                ScatterDataHndl = scatter(app.MainUIAxes, app.ExperimentalData(:, 1), ...
                    app.ExperimentalData(:, 2), 18, ...
                    'MarkerEdgeColor', [0.75 0.22 0.17], ...
                    'MarkerFaceColor', 'none', ...
                    'DisplayName', 'Experimental data');
            end
    
            function setupMainAxes(app)
                set(app.MainUIAxes, Box = 'on');
            end
    
            function setupSimulation(app)
                if ~app.IsDataLoaded
                    app.ExperimentalData(:, 1) = 30 : 0.1 : 70;
                    app.ExperimentalData(:, 2) = NaN;
                end
            end
    
            function updateSliderValue(app)
                app.nSlider.Value = app.Layers{app.SelectedLayer, 2};
                app.kSlider.Value = app.Layers{app.SelectedLayer, 5};
                app.dSlider.Value = app.Layers{app.SelectedLayer, 8};
            end
    
            function sliderChangesTableSelection(app, ParamIdx)
                app.LayerParamUITable.Selection = [app.SelectedLayer, ParamIdx];
            end
    
            function simulateData(app, Mode)
                app.StatusLamp.Color = [1,0,0];
                app.StatusTextArea.Value = "Simulation in progress...";
                if strcmp(Mode, 'Auto')
                    RelatedColumns = reshape(1:9, 3, 3)';
                    for l = 1 : height(app.UsedLayers)
                        for c = 1 : height(RelatedColumns)
                            ReshapedLayers{l, c} = cell2mat(app.UsedLayers(l, RelatedColumns(c, :))); %#ok<*AGROW>
                            if  numel(ReshapedLayers{l, c}) == 3
                                ReshapedLayers{l, c}(2) = [];
                            end
                        end
                    end
                    LayersWithRanges = cellfun(@(X) numel(X) == 2, ReshapedLayers);
                    AutofitInterval = num2cell(  cellfun( @(X) round(mean(X), 1, 'significant') ...
                        / app.AutofitSlider.Value, ReshapedLayers(LayersWithRanges) )  );
                    AutofitVectors = ReshapedLayers;
                    AutofitVectors(LayersWithRanges) = cellfun( @(X, Y) X(1) : Y : X(end), ...
                        AutofitVectors(LayersWithRanges), AutofitInterval, UniformOutput = false );
                    SimulationLayers = combvec(AutofitVectors{:});
                    SimulationLayers = reshape(SimulationLayers, [], 3, size(SimulationLayers, 2));
                    SimulationLayers(:, 3, :) = 1e-9 * SimulationLayers(:, 3, :);
                    SimulationLayers(:, 4, :) = arrayfun(@(n, k) complex(n.^2 - k.^2, 2 .* n .* k), ...
                        SimulationLayers(:, 1, :), SimulationLayers(:, 2, :)); % Dielectric constant         
                    NumOfIterations = size(SimulationLayers, 3);
                elseif strcmp(Mode, 'Manual')
                    for i = 1 : height(app.UsedLayers)
                        for j = 1 : width(app.UsedLayers)
                            if isempty(app.UsedLayers{i, j})
                                SimulationLayers(i, j) = NaN;
                            else
                                SimulationLayers(i, j) = app.UsedLayers{i, j};
                            end
                        end
                    end
                    SimulationLayers = SimulationLayers(:, [2, 5, 8]);
                    SimulationLayers(:, 3, :) = 1e-9 * SimulationLayers(:, 3, :);
                    SimulationLayers(:, 4, :) = arrayfun(@(n, k) complex(n.^2 - k.^2, 2 .* n .* k), ...
                        SimulationLayers(:, 1, :), SimulationLayers(:, 2, :)); % Dielectric constant
                    NumOfIterations = 1;
                end
    
                qCalculate = @(n1, e, th) sqrt( e - n1.^2 * sin(th).^2 ) ./ e;
                betaCalculate = @(n1, d, e, th) (2*d*pi / app.Lambda) .* sqrt(e - n1.^2*sin(th).^2);
    
                % Apply angle range filter only if checkbox is on
                % Choose source data — interpolated or original
                if app.InterpCheckBox.Value && ~isempty(app.InterpolatedData)
                    SourceTheta = app.InterpolatedData(:, 1);
                    SourceR     = app.InterpolatedData(:, 2);
                else
                    SourceTheta = app.ThetaRange;
                    SourceR     = app.ExperimentalData(:, 2);
                end


                % Apply angle range filter
                if app.IsDataLoaded && app.AngleRangeCheckBox.Value
                    AngleFilter   = SourceTheta >= app.AngleMin & SourceTheta <= app.AngleMax;
                    FilteredTheta = SourceTheta(AngleFilter);
                    FilteredData  = SourceR(AngleFilter);
                else
                    FilteredTheta = SourceTheta;
                    FilteredData  = SourceR;
                end


                % --- Build ExportThetaVec and ExportR (after FilteredTheta is defined) ---
                exportStart = FilteredTheta(1);
                exportEnd   = FilteredTheta(end);
    
                angleSpan = max(0, exportEnd - exportStart);
    
                if isprop(app, 'PointsPerDegree') && app.PointsPerDegree > 0
                    ExportNumPts = max(2, ceil(angleSpan * app.PointsPerDegree));
                elseif ~isempty(app.NumSimPoints) && app.NumSimPoints > 1
                    ExportNumPts = app.NumSimPoints;
                else
                    ExportNumPts = 500;
                end
    
                ExportThetaVec = linspace(exportStart, exportEnd, ExportNumPts)';
    
                % Sample simulation curve if available
                if ~isempty(app.SmoothSimTheta) && ~isempty(app.SmoothSimR)
                    ExportR = interp1(app.SmoothSimTheta(:), app.SmoothSimR(:), ExportThetaVec, 'spline', NaN);
                elseif ~isempty(app.SimulatedTheta) && ~isempty(app.SimulatedR)
                    ExportR = interp1(app.SimulatedTheta(:), app.SimulatedR(:), ExportThetaVec, 'spline', NaN);
                else
                    ExportR = NaN(size(ExportThetaVec));
                end
    
                % Experimental reflectance at exported angles
                if ~isempty(app.ExperimentalData)
                    ExpRefAtSim = interp1(app.ExperimentalData(:,1), app.ExperimentalData(:,2), ExportThetaVec, 'linear', 'extrap');
                else
                    ExpRefAtSim = NaN(size(ExportThetaVec));
                end


                ThetaRangeRad = deg2rad(FilteredTheta);
    
    
                [RMSE, Corr2] = deal(NaN(NumOfIterations, 1));
                [R, T] = deal(NaN(length(ThetaRangeRad), NumOfIterations));
                BestSet = 1;
                HasCI = false;
    
    
                if strcmp(Mode, 'Auto')
                    ProgressDlg = uiprogressdlg(app.UIFigure, ...
                        Title = 'Auto-fitting', ...
                        Message = 'Starting...', ...
                        Cancelable = 'on', ...
                        CancelText = 'Stop');
                end
    
                for s = 1 : NumOfIterations
                    % Calculations
                    Theta = ThetaRangeRad;
                    for t = 1 : length(Theta)
                        q = qCalculate(SimulationLayers(1, 1, s), SimulationLayers(:, 4, s), Theta(t));
                        Beta = betaCalculate(SimulationLayers(1, 1, s), SimulationLayers(:, 3, s), SimulationLayers(:, 4, s), Theta(t));
                        for l = 2 : height(SimulationLayers) - 1
                            m(l, 1, 1) = cos(Beta(l));
                            m(l, 1, 2) = -1i*sin(Beta(l)) / q(l);
                            m(l, 2, 1) = -1i*sin(Beta(l)) * q(l);
                            m(l, 2, 2) = cos(Beta(l));
                        end
                        M = [1 0; 0 1];
                        for l = 2 : height(SimulationLayers) - 1
                            M1(:, :) = m(l, :, :);
                            M = M * M1;
                        end
                        rp = ( (M(1, 1)+M(1, 2)*q(end))*q(1) - (M(2, 1)+M(2, 2)*q(end)) ) / ...
                            ( (M(1, 1)+M(1, 2)*q(end))*q(1) + (M(2, 1)+M(2, 2)*q(end)) );
                        tp = 2*q(1) / ( (M(1, 1)+M(1, 2)*q(end)) * q(1)+(M(2, 1)+M(2, 2)*q(end)) );
                        R(t, s) = rp * conj(rp); % Reflectance
                        T(t, s) = tp*conj(tp) / cos(Theta(t))*SimulationLayers(1, 1, s)*q(end); % Transmission
                  
                    end


                    % Correlation values
                    ThetaVecDeg = FilteredTheta;
                    if length(FilteredData) == size(R, 1)
                        Corr = corrcoef(FilteredData, R(:, s));
                        Corr2(s) = Corr(1, 2)^2;
                        RMSE(s) = sqrt(sse(FilteredData, R(:, s)) / (length(FilteredData) - 1));
                    else
                        Corr2(s) = NaN;
                        RMSE(s)  = NaN;
                    end


                    % Plotting
                    if (strcmp(Mode, 'Auto') && RMSE(s) == min(RMSE)) || strcmp(Mode, 'Manual')
                        
                        % --- DYNAMIC RANGE: No hardcoded limits ---
                        if app.AngleRangeCheckBox.Value
                            % Use the exact limits the user typed into the UI
                            minPlotAngle = app.AngleMinEditField.Value;
                            maxPlotAngle = app.AngleMaxEditField.Value;
                        else
                            % No limit: Plot the full physical angle spectrum (0 to 90 degrees)
                            minPlotAngle = 0;
                            maxPlotAngle = 90; 
                        end
                        
                        % Generate the points for the simulation curve
                        SmoothThetaVec = linspace(minPlotAngle, maxPlotAngle, 1000);
                        SmoothThetaRad = deg2rad(SmoothThetaVec);
                        SmoothR = zeros(length(SmoothThetaRad), 1);
                        
                        % Recalculate physical reflectance explicitly for the new dynamic range
                        for t = 1 : length(SmoothThetaRad)
                            q = qCalculate(SimulationLayers(1, 1, s), SimulationLayers(:, 4, s), SmoothThetaRad(t));
                            Beta = betaCalculate(SimulationLayers(1, 1, s), SimulationLayers(:, 3, s), SimulationLayers(:, 4, s), SmoothThetaRad(t));
                            for l = 2 : height(SimulationLayers) - 1
                                m(l, 1, 1) = cos(Beta(l));
                                m(l, 1, 2) = -1i*sin(Beta(l)) / q(l);
                                m(l, 2, 1) = -1i*sin(Beta(l)) * q(l);
                                m(l, 2, 2) = cos(Beta(l));
                            end
                            M = [1 0; 0 1];
                            for l = 2 : height(SimulationLayers) - 1
                                M1(:, :) = m(l, :, :);
                                M = M * M1;
                            end
                            rp = ( (M(1, 1)+M(1, 2)*q(end))*q(1) - (M(2, 1)+M(2, 2)*q(end)) ) / ...
                                ( (M(1, 1)+M(1, 2)*q(end))*q(1) + (M(2, 1)+M(2, 2)*q(end)) );
                            SmoothR(t) = rp * conj(rp); 
                        end

                        % Dynamically update the X-axis to fit whatever range was just calculated
                        app.MainUIAxes.XLim = [minPlotAngle, maxPlotAngle];
    
                        % Export data logic 
                        if app.InterpCheckBox.Value
                            % Interpolation on — use spinner value
                            ExportNumPts = app.NumSimPoints;
                            ExportThetaVec = linspace(minPlotAngle, maxPlotAngle, ExportNumPts);
                            ExportR = interp1(SmoothThetaVec, SmoothR, ExportThetaVec, 'spline');
    
                        elseif app.AngleRangeCheckBox.Value
                            % Specific range on, no interpolation — use only original points within range
                            AngleFilter = app.ExperimentalData(:,1) >= minPlotAngle & ...
                                app.ExperimentalData(:,1) <= maxPlotAngle;
                            ExportThetaVec = app.ExperimentalData(AngleFilter, 1)';
                            ExportR = interp1(SmoothThetaVec, SmoothR, ExportThetaVec, 'linear', 'extrap');
    
                        else
                            % No range, no interpolation — use all original experimental angles
                            ExportThetaVec = app.ExperimentalData(:, 1)';
                            ExportR = interp1(SmoothThetaVec, SmoothR, ExportThetaVec, 'linear', 'extrap');
                        end
    
                        try %#ok<TRYNC>
                            delete(app.SimHndl);
                            delete(ParamHndl);
                        end
                        hold(app.MainUIAxes, 'on');
                        app.SimHndl = plot(app.MainUIAxes, SmoothThetaVec, SmoothR, ...
                            'Color', [0.10 0.23 0.36], ...
                            'LineWidth', 1.8, ...
                            'DisplayName', 'TMM Simulation');
                        % ParamHndl = annotation('textbox', [0.14,0.12,0.27,0.12], String = composeParams(s, AutofitLayers, RMSE, Corr2),...
                        %     Interpreter = 'latex');
                        drawnow;
                        BestSet = s;
    
                        % Draw resonance angle vertical line
                        [~, MinIdx] = min(SmoothR);
                        ResonanceAngle = SmoothThetaVec(MinIdx);
                        delete(findobj(app.MainUIAxes, 'Tag', 'ResonanceLine'));
                        xline(app.MainUIAxes, ResonanceAngle, '--r', ...
                            sprintf('\\theta_{res} = %.2f°', ResonanceAngle), ...
                            LineWidth = 1.5, ...
                            LabelVerticalAlignment = 'bottom', ...
                            LabelHorizontalAlignment = 'right', ...
                            Tag = 'ResonanceLine');
    
                        % FWHM calculation
                        HalfMax = (min(SmoothR) + max(SmoothR)) / 2;
                        AboveHalf = SmoothR >= HalfMax;
                        Transitions = diff(AboveHalf);
                        FallingEdge = find(Transitions == -1, 1, 'first');
                        RisingEdge  = find(Transitions == 1,  1, 'last');
                        if ~isempty(FallingEdge) && ~isempty(RisingEdge)
                            FWHM = SmoothThetaVec(RisingEdge) - SmoothThetaVec(FallingEdge);
                            % Draw FWHM bracket on plot
                            delete(findobj(app.MainUIAxes, 'Tag', 'FWHMLine'));
                            FWHMLineStyle = '--k';
                            FWHMColor = [0 0 0];
                            if app.IsDarkMode
                                FWHMLineStyle = '--w';
                                FWHMColor = [1 1 1];
                            end
                            yline(app.MainUIAxes, HalfMax, FWHMLineStyle, ...
                                sprintf('FWHM = %.2f°', FWHM), ...
                                LineWidth = 1, ...
                                LabelVerticalAlignment = 'bottom', ...
                                LabelHorizontalAlignment = 'left', ...
                                Tag = 'FWHMLine');
                            FWHMLineObj = findobj(app.MainUIAxes, 'Tag', 'FWHMLine');
                            if ~isempty(FWHMLineObj)
                                FWHMLineObj.LabelColor = FWHMColor;
                            end
                        else
                            FWHM = NaN;
                        end
    
                        layerLines = {};
                        for li = 1 : height(SimulationLayers)
                            layerLines{end+1} = sprintf('Layer %d:  n = %.4f  |  k = %.4f  |  d = %.4f nm', ...
                                li, SimulationLayers(li,1,BestSet), SimulationLayers(li,2,BestSet), ...
                                SimulationLayers(li,3,BestSet) / 1e-9);
                        end


                        app.RMSEValueLabel.Text  = sprintf('%.4f', RMSE(BestSet));
                        app.R2ValueLabel.Text    = sprintf('%.4f', Corr2(BestSet));
                        app.ThetaValueLabel.Text = sprintf('%.2f', ResonanceAngle);
                        app.FWHMValueLabel.Text  = sprintf('%.2f', FWHM);
    
                        % RMSE card color
                        if RMSE(BestSet) > 0.05
                            app.RMSEPanel.BackgroundColor = [0.99 0.95 0.89];
                            app.RMSEPanel.HighlightColor  = [0.91 0.77 0.48];
                        else
                            app.RMSEPanel.BackgroundColor = [0.91 0.96 0.93];
                            app.RMSEPanel.HighlightColor  = [0.55 0.78 0.62];
                        end
    
                        % R² card color
                        if Corr2(BestSet) >= 0.95
                            app.R2Panel.BackgroundColor = [0.91 0.96 0.93];
                            app.R2Panel.HighlightColor  = [0.55 0.78 0.62];
                        elseif Corr2(BestSet) >= 0.90
                            app.R2Panel.BackgroundColor = [0.99 0.95 0.89];
                            app.R2Panel.HighlightColor  = [0.91 0.77 0.48];
                        else
                            app.R2Panel.BackgroundColor = [0.99 0.91 0.91];
                            app.R2Panel.HighlightColor  = [0.88 0.50 0.50];
                        end
    
                        % Theta card color
                        if ResonanceAngle >= 40 && ResonanceAngle <= 70
                            app.ThetaPanel.BackgroundColor = [0.91 0.96 0.93];
                            app.ThetaPanel.HighlightColor  = [0.55 0.78 0.62];
                        else
                            app.ThetaPanel.BackgroundColor = [0.99 0.91 0.91];
                            app.ThetaPanel.HighlightColor  = [0.88 0.50 0.50];
                        end
    
                        % FWHM card color
                        if ~isnan(FWHM) && FWHM <= 5
                            app.FWHMPanel.BackgroundColor = [0.91 0.96 0.93];
                            app.FWHMPanel.HighlightColor  = [0.55 0.78 0.62];
                        elseif ~isnan(FWHM) && FWHM <= 10
                            app.FWHMPanel.BackgroundColor = [0.99 0.95 0.89];
                            app.FWHMPanel.HighlightColor  = [0.91 0.77 0.48];
                        else
                            app.FWHMPanel.BackgroundColor = [0.99 0.91 0.91];
                            app.FWHMPanel.HighlightColor  = [0.88 0.50 0.50];
                        end
    
                        nkdLines = {};
                        for li = 1 : height(SimulationLayers)
                            n_val = SimulationLayers(li, 1, BestSet);
                            k_val = SimulationLayers(li, 2, BestSet);
                            d_val = SimulationLayers(li, 3, BestSet) / 1e-9;
    
                            if HasCI
                                n_str = sprintf('%.4f', n_val);
                                k_str = sprintf('%.4f', k_val);
                                d_str = sprintf('%.4f nm', d_val);
    
                                if app.ConfidenceIntervals(li).n_varied
                                    n_str = sprintf('%.4f [%.4f – %.4f]', n_val, ...
                                        app.ConfidenceIntervals(li).n_min, app.ConfidenceIntervals(li).n_max);
                                end
                                if app.ConfidenceIntervals(li).k_varied
                                    k_str = sprintf('%.4f [%.4f – %.4f]', k_val, ...
                                        app.ConfidenceIntervals(li).k_min, app.ConfidenceIntervals(li).k_max);
                                end
                                if app.ConfidenceIntervals(li).d_varied
                                    d_str = sprintf('%.4f [%.4f – %.4f] nm', d_val, ...
                                        app.ConfidenceIntervals(li).d_min, app.ConfidenceIntervals(li).d_max);
                                end
    
                                nkdLines{end+1} = sprintf('Layer %d:  n = %s  |  k = %s  |  d = %s', ...
                                    li, n_str, k_str, d_str);
                            else
                                nkdLines{end+1} = sprintf('Layer %d:  n = %.4f  |  k = %.4f  |  d = %.4f nm', ...
                                    li, n_val, k_val, d_val);
                            end
                        end
                        app.nkdvalueTextArea.Value = nkdLines;
                    end
                      
                    % SmoothThetaVec = ThetaVecDeg(1) : 0.1 : ThetaVecDeg(end);
                    % SmoothR = spline(ThetaVecDeg, R(:, s), SmoothThetaVec);
                    % SimHndl = plot(SmoothThetaVec, SmoothR, 'b', DisplayName = 'Prediction');
    
                   
                    % Progress
                    if strcmp(Mode, 'Auto')
                        ProgressPercent = s / NumOfIterations;
                        ProgressDlg.Value = ProgressPercent;
                        ProgressDlg.Message = sprintf('Iteration %d of %d  (%.1f%%)', s, NumOfIterations, ProgressPercent*100);
                        if ProgressDlg.CancelRequested
                            break;
                        end
                    end
                end
    
                 if strcmp(Mode, 'Auto') && isvalid(ProgressDlg)
                    close(ProgressDlg);
                 end
    
                 % Calculate confidence intervals from RMSE landscape
                 if strcmp(Mode, 'Auto') && NumOfIterations > 1
    
                     % Tolerance — sets within 5% of best RMSE are considered acceptable
                     RMSETolerance = RMSE(BestSet) * 1.05;
                     AcceptableSets = find(RMSE <= RMSETolerance);
    
                     if length(AcceptableSets) > 1
                         % Extract parameter values for all acceptable sets
                         for li = 1 : height(SimulationLayers)
                             n_accepted = squeeze(real(SimulationLayers(li, 1, AcceptableSets)));
                             k_accepted = squeeze(real(SimulationLayers(li, 2, AcceptableSets)));
                             d_accepted = squeeze(real(SimulationLayers(li, 3, AcceptableSets))) / 1e-9;
    
                             app.ConfidenceIntervals(li).n_min  = min(n_accepted);
                             app.ConfidenceIntervals(li).n_max  = max(n_accepted);
                             app.ConfidenceIntervals(li).k_min  = min(k_accepted);
                             app.ConfidenceIntervals(li).k_max  = max(k_accepted);
                             app.ConfidenceIntervals(li).d_min  = min(d_accepted);
                             app.ConfidenceIntervals(li).d_max  = max(d_accepted);
    
                             % Flag whether this layer actually had a range fitted
                             app.ConfidenceIntervals(li).n_varied = (app.ConfidenceIntervals(li).n_max - app.ConfidenceIntervals(li).n_min) > 1e-6;
                             app.ConfidenceIntervals(li).k_varied = (app.ConfidenceIntervals(li).k_max - app.ConfidenceIntervals(li).k_min) > 1e-6;
                             app.ConfidenceIntervals(li).d_varied = (app.ConfidenceIntervals(li).d_max - app.ConfidenceIntervals(li).d_min) > 1e-6;
                         end
    
                         HasCI = true;
                     else
                         HasCI = false;
                     end
                 else
                     HasCI = false;
                 end


                 app.StatusLamp.Color = [0,1,0];
                 app.StatusTextArea.Value = "Simulation completes.";
                 
    
                 for li = 1 : height(SimulationLayers)
                     app.LayerParamUITable.Data(li, 2) = num2cell(SimulationLayers(li, 1, BestSet));
                     app.LayerParamUITable.Data(li, 5) = num2cell(SimulationLayers(li, 2, BestSet));
                     app.LayerParamUITable.Data(li, 8) = num2cell(SimulationLayers(li, 3, BestSet) / 1e-9);
    
                     % Sync best fit values back to app.Layers
                     app.Layers{li, 2} = SimulationLayers(li, 1, BestSet);
                     app.Layers{li, 5} = SimulationLayers(li, 2, BestSet);
                     app.Layers{li, 8} = SimulationLayers(li, 3, BestSet) / 1e-9;
                 end
                 app.UsedLayers = app.Layers(1 : app.NumOfLayersSpinner.Value, :);
    
                 % Save simulated results for Results table
                 % Save export-resolution data for table and export
                 app.SimulatedTheta = ExportThetaVec;
                 app.SimulatedR     = real(ExportR);
    
                 % Store smooth curve for export interpolation
                 app.SmoothSimTheta = SmoothThetaVec;
                 app.SmoothSimR     = real(SmoothR);
    
                 updateResultsTable(app);

                 
            end
    
            function resetStatus(app)
                if isvalid(app)
                    app.StatusTextArea.Value = app.DefaultStatus;
                end
            end
    
            function updateResultsTable(app)

                if isempty(app.ExperimentalData) || isempty(app.SimulatedR)
                    return;
                end

                NumPoints = length(app.SimulatedTheta);
                TableData = cell(NumPoints, 3);
                for i = 1 : NumPoints
                    SimAngle = app.SimulatedTheta(i);
                    TableData{i, 1} = SimAngle;

                    % Match nearest experimental data point
                    [~, MatchIdx] = min(abs(app.ExperimentalData(:,1) - SimAngle));
                    TableData{i, 2} = app.ExperimentalData(MatchIdx, 2);

                    % Use smooth sim value
                    TableData{i, 3} = app.SimulatedR(i);
                end

                app.ResultsTable.Data = TableData;
            end

            
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.StatusTextArea.Value = app.DefaultStatus;
                app.UIFigure.Name = sprintf('%s v%s', app.AppShortName, app.AppVersion);
                app.AppNameLabel.Text = sprintf('%s (%s) v%s', app.AppFullName, app.AppShortName, app.AppVersion);
                app.LayerSelectionDropDown.Items = listLayers(app);
                app.LambdaEditField.Value = app.Lambda / 1e-9;
                app.PrismAngleEditField.Value = app.PrismDegree;
                app.SelectedLayer = app.NumOfLayersSpinner.Value;
                app.AutoButton.Enable = 'off';
                PrismTypeDropDownValueChanged(app);
                FittingModeButtonGroupSelectionChanged(app);
                app.InterpCheckBox.Value = false;
                app.InterpPointsSpinner.Enable = 'off';
                app.InterpPointsSpinner.Value = 60;
                app.NumSimPoints = 60;  % set after spinner value is set
                app.IsDarkMode = false;
                applyTheme(app);
    
                app.AngleRangeCheckBox.Value = false;
                
                app.AngleMinEditField.Visible   = 'off';
                app.AngleMaxEditField.Visible   = 'off';
    
                app.AngleMin = 30;
                app.AngleMax = 70;
                app.AngleMinEditField.Value = 30;
                app.AngleMaxEditField.Value = 70;
    
                TableColumnNames = {'𝑛₁', '𝑛', '𝑛₂', 'k₁', 'k', 'k₂', '𝑑₁', '𝑑', '𝑑₂'};
                set(app.LayerParamUITable, ColumnName = TableColumnNames, RowName = 'numbered', ...
                    ColumnEditable = false, ColumnWidth = repelem({45}, 1, 9)) ;
               
                setInitialSlider(app, true, true);
                updateParamTable(app);
                set(app.ResetRangesButton, Visible = 'off', Enable = 'off');
                ButtonTuningSliderValueChanged(app);
                LayerSelectionDropDownValueChanged(app);
                    setupMainAxes(app);
        set(app.ResultsTable, ...
            ColumnName = {'Angle (deg)', 'Reflectance (Exp)', 'Reflectance (Sim)'}, ...
            ColumnWidth = {120, 150, 150}, ...
            ColumnEditable = false, ...
            RowName = 'numbered');
        end

        % Value changed function: ButtonTuningSlider
        function ButtonTuningSliderValueChanged(app, event)
                app.ButtonTuningSlider.Value = round(app.ButtonTuningSlider.Value, 0);
                app.ButtonTuningSize = app.ButtonTuningSlider.Value;
                app.ParamTuningSize = [mean(app.nSlider.Limits), mean(app.kSlider.Limits), mean(app.dSlider.Limits)] ... 
                    / 10^app.ButtonTuningSize;
        end

        % Value changing function: AutofitSlider
        function AutofitSliderValueChanging(app, event)
                app.AutofitSlider.Value = round(event.Value, 0);
                app.AutofitValueLabel.Text = num2str(app.AutofitSlider.Value);
        end

        % Value changed function: NumOfLayersSpinner
        function NumOfLayersSpinnerValueChanged(app, event)
                app.UsedLayers = app.Layers(1:event.Value, :);
                app.LayerSelectionDropDown.Items = listLayers(app);
                updateParamTable(app);
        end

        % Value changing function: nSlider
        function nSliderValueChanging(app, event)
                app.Layers{app.SelectedLayer, 2} = event.Value;
                updateParamTable(app);
                sliderChangesTableSelection(app, 2);
                if app.ManualButton.Value && ~isempty(app.ThetaRange)
                    simulateData(app,'Manual');
                end
        end

        % Value changed function: LayerSelectionDropDown
        function LayerSelectionDropDownValueChanged(app, event)
                app.SelectedLayer = str2double(erase(app.LayerSelectionDropDown.Value, "Layer "));
                updateSliderValue(app);
        end

        % Value changing function: kSlider
        function kSliderValueChanging(app, event)
                app.Layers{app.SelectedLayer, 5} = event.Value;
                updateParamTable(app);
                sliderChangesTableSelection(app, 5);
                if app.ManualButton.Value && ~isempty(app.ThetaRange)
                    simulateData(app,'Manual');
                end
        end

        % Value changing function: dSlider
        function dSliderValueChanging(app, event)
                app.Layers{app.SelectedLayer, 8} = event.Value;
                updateParamTable(app);
                sliderChangesTableSelection(app, 8);
                if app.ManualButton.Value && ~isempty(app.ThetaRange)
                    simulateData(app,'Manual');
                end
        end

        % Value changed function: nSlider
        function nSliderValueChanged(app, event)
                extendSliderRange(app, event.Value, app.nSlider, 0.5)
        end

        % Value changed function: kSlider
        function kSliderValueChanged(app, event)
                extendSliderRange(app, event.Value, app.kSlider, 0.5)
        end

        % Value changed function: dSlider
        function dSliderValueChanged(app, event)
                extendSliderRange(app, event.Value, app.dSlider, 5)
        end

        % Button pushed function: ResetRangesButton
        function ResetRangesButtonPushed(app, event)
                setInitialSlider(app, true, false);
                set(app.ResetRangesButton, Visible = 'off', Enable = 'off');
        end

        % Button pushed function: dLeftButton, dRightButton, kLeftButton, 
        % ...and 3 other components
        function TuningButtonPushed(app, event)
                PushedButton = event.Source.Tag;
                ColIdx = app.ColumnIdx(PushedButton(1) == app.ParamLetters);
                ExtendValue = [0.5, 0.5, 5];
                Sign = PushedButton(2);
                sliderChangesTableSelection(app, ColIdx);
    
                if Sign == 'r'
                    Change = + app.ParamTuningSize(PushedButton(1) == app.ParamLetters);
                else
                    Change = - app.ParamTuningSize(PushedButton(1) == app.ParamLetters);
                end
    
                SliderName = PushedButton(1) + "Slider";
                NewValue = app.(SliderName).Value + Change;
                if NewValue > app.(SliderName).Limits(1) && NewValue < app.(SliderName).Limits(2)
                    app.(SliderName).Value = NewValue;
                else
                    extendSliderRange(app, NewValue, app.(SliderName), ExtendValue(ColIdx));
                    app.(SliderName).Value = max(NewValue, app.(SliderName).Limits(1));
                end
    
                % Sync ALL parameters from sliders back to Layers
                app.Layers{app.SelectedLayer, 2} = app.nSlider.Value;
                app.Layers{app.SelectedLayer, 5} = app.kSlider.Value;
                app.Layers{app.SelectedLayer, 8} = app.dSlider.Value;
    
                app.UsedLayers = app.Layers(1:app.NumOfLayersSpinner.Value, :);
                updateParamTable(app);
    
                % Auto-simulate in Manual mode
                if app.ManualButton.Value && ~isempty(app.ThetaRange)
                    simulateData(app, 'Manual');
                end
            
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
                switch app.LoaddataButton.Text
                    case 'Load data'
                        % --- NEW: Check if a previous path exists, otherwise default to current folder ---
                        if isempty(app.DataPath) || ~isfolder(app.DataPath)
                            startPath = pwd; % Default to current working directory
                        else
                            startPath = app.DataPath; % Use the last used folder
                        end
                        
                        % Pass the startPath into uigetfile
                        [DataName, app.DataPath] = uigetfile(...
                            {'*.txt;*.csv;*.xlsx;*.xls', 'Supported files (*.txt, *.csv, *.xlsx, *.xls)'; '*.*', 'All files (*.*)'}, ...
                            'Load experimental data', ...
                            startPath); % <--- Added startPath here
                        
                        % Check if the user cancelled the dialog window
                        if isequal(DataName, 0)
                            return;
                        end
                        
                        figure(app.UIFigure);
                        DataFullPath = fullfile(app.DataPath, DataName);
                        try
                            if endsWith(DataFullPath, '.xls', 'IgnoreCase', true)
                                app.RawData = readmatrix(DataFullPath, 'FileType', 'spreadsheet');
                            else
                                app.RawData = readmatrix(DataFullPath);
                            end
                            app.RawData = rmmissing(app.RawData);
                            selectDataColumns(app);
                            if isempty(app.ExperimentalData)
                                return;
                            end
    
                            % Save raw angles before conversion
                            app.RawThetaRange = app.ExperimentalData(:, 1);
    
                            app.ThetaRange = app.ExperimentalData(:, 1);
    
                            % Auto-set angle range to match loaded data
                            app.AngleMin = min(app.ExperimentalData(:, 1));
                            app.AngleMax = max(app.ExperimentalData(:, 1));
                            app.AngleMinEditField.Value = app.AngleMin;
                            app.AngleMaxEditField.Value = app.AngleMax; % Fixed missing semicolon here
    
                            app.LoadDataTextArea.Value = DataFullPath;
                            app.LoaddataButton.Text = 'Clear data';
                            app.ScatterDataHndl = plotExperimentalData(app);
                            updateStatus(app, "Data has been loaded and plotted.");
                            app.IsDataLoaded = true;
                            app.AutoButton.Enable = 'on';
                        catch
                            updateStatus(app, "(!) Data could not be read. Only *.txt, *.csv, *.xlsx, *.xls are supported.")
                        end
                end
        end

        % Selection changed function: FittingModeButtonGroup
        function FittingModeButtonGroupSelectionChanged(app, event)
                app.FittingMode = app.FittingModeButtonGroup.SelectedObject.Text;
    
                ManualFitGroup =  [app.nSlider, app.kSlider, app.dSlider, ...
                    app.SliderLabel, app.kLabel, app.nmSliderLabel, ...
                    app.nRightButton, app.nLeftButton, ...
                    app.kLeftButton, app.kRightButton, app.dLeftButton, ...
                    app.dRightButton, app.LayerSelectionDropDown, app.ButtonTuningLabel, ...
                    app.ButtonTuningSlider];
                AutoFitGroup = [app.AutofitLabel, app.AutofitRunButton, app.AutofitSlider, ...
                    app.AngleRangeCheckBox];
                set([ManualFitGroup, AutoFitGroup], Enable = 'off');
                set(ManualFitGroup, Enable = 'on');
                if strcmp(app.FittingMode, 'Auto')
                    set(AutoFitGroup, Enable = 'on');
                end
    
                switch app.FittingMode
                    case 'Manual'
                        InactiveColumnColor = uistyle(BackgroundColor = [0.9, 0.9, 0.9]);
                        addStyle(app.LayerParamUITable, InactiveColumnColor, 'column', ...
                            setdiff(1:9, app.ColumnIdx));
                    case 'Auto'
                        app.LayerParamUITable.ColumnEditable( setdiff(1:9, app.ColumnIdx)) = true;
                        removeStyle(app.LayerParamUITable);
                end
    
        end

        % Cell edit callback: LayerParamUITable
        function LayerParamUITableCellEdit(app, event)
                % Validate input value
                if isnumeric(event.NewData)
                    NewData = event.NewData;
                else
                    NewData = str2double(event.NewData);
                end
    
                if isnan(NewData)
                    app.LayerParamUITable.Data{event.Indices(1), event.Indices(2)} = [];
                    updateStatus(app, 'Please enter numeric value only.');
                    return;
                end
                app.Layers{event.Indices(1), event.Indices(2)} = NewData;
                app.UsedLayers = app.Layers(1:app.NumOfLayersSpinner.Value, :);
        end

        % Button pushed function: AutofitRunButton
        function AutofitRunButtonPushed(app, event)
               
                % Validate angle range only if checkbox is on
                if app.AngleRangeCheckBox.Value
                    if app.AngleMinEditField.Value >= app.AngleMaxEditField.Value
                        updateStatus(app, '(!) Angle min must be less than angle max.');
                        return;
                    end
                    if app.IsDataLoaded
                        DataMin = min(app.ExperimentalData(:, 1));
                        DataMax = max(app.ExperimentalData(:, 1));
                        if app.AngleMin < DataMin || app.AngleMax > DataMax
                            updateStatus(app, sprintf('(!) Angle range must be within data range (%.2f° – %.2f°).', DataMin, DataMax));
                            return;
                        end
                    end
                end
                
                % Check error cells
                ErrorCellColor = uistyle('BackgroundColor', [1, 0.8, 0.8]);
    
                ErrorCell = false(size(app.UsedLayers));
                ErrorCellTimer = timer(StartDelay = 1, ...
                    TimerFcn = @(~, ~) removeStyle(app.LayerParamUITable));
    
                IsNoLimitsEntered = all(cellfun(@isempty, app.UsedLayers(:, setdiff(1:9, app.ColumnIdx))), 'all');
                if IsNoLimitsEntered
                    updateStatus(app, 'No limits were entered');
                    ErrorCell(:, setdiff(1:9, app.ColumnIdx)) = true;
                    [ErrorCellIdx(:, 1), ErrorCellIdx(:, 2)] = find(ErrorCell);
                    addStyle(app.LayerParamUITable, ErrorCellColor, 'cell', ErrorCellIdx);
                    start(ErrorCellTimer);
                    return;
                end
    
                for l = 1 : height(app.UsedLayers)
                    for c = app.ColumnIdx
                        if xor(isempty(app.UsedLayers{l, c-1}), isempty(app.UsedLayers{l, c+1}))                
                            ErrorCell(l, [c-1, c+1]) = true;
                        end
                        if all(~cellfun(@isempty, app.UsedLayers(l, [c-1, c+1]))) && ...
                                app.UsedLayers{l, c-1} > app.UsedLayers{l, c+1}
                            ErrorCell(l, [c-1, c+1]) = true;
                        end
                    end
                end
                if any(ErrorCell, 'all')
                    [ErrorCellIdx(:, 1), ErrorCellIdx(:, 2)] = find(ErrorCell);
                    addStyle(app.LayerParamUITable, ErrorCellColor, 'cell', ErrorCellIdx);
                    start(ErrorCellTimer);
                    return;
                end
    
                % Validate angle range
                if app.AngleMinEditField.Value >= app.AngleMaxEditField.Value
                    updateStatus(app, '(!) Angle min must be less than angle max.');
                    return;
                end
    
                if app.IsDataLoaded
                    DataMin = min(app.ExperimentalData(:, 1));
                    DataMax = max(app.ExperimentalData(:, 1));
                    if app.AngleMin < DataMin || app.AngleMax > DataMax
                        updateStatus(app, sprintf('(!) Angle range must be within data range (%.2f° – %.2f°).', DataMin, DataMax));
                        return;
                    end
                end
                simulateData(app, 'Auto');


        end

        % Value changed function: LambdaEditField
        function LambdaEditFieldValueChanged(app, event)
                app.Lambda = app.LambdaEditField.Value * 1e-9;
        end

        % Selection changed function: LayerParamUITable
        function LayerParamUITableSelectionChanged(app, event)
                app.SelectedCell = app.LayerParamUITable.Selection;
                app.LayerSelectionDropDown.Value = app.LayerSelectionDropDown.Items(app.SelectedCell(1));
               
                LayerSelectionDropDownValueChanged(app);
               
                if strcmp(app.FittingMode, 'Manual') && ~any(app.SelectedCell(2) == app.ColumnIdx)
                    ShiftedIdx = deflectCellSelection;
                    app.LayerParamUITable.Selection(2) = ShiftedIdx;
                end
    
                app.LayerSelectionDropDown.FontWeight = 'bold';
                ObjectHighlighted = app.LayerSelectionDropDown; 
                if any(app.SelectedCell(2) == app.ColumnIdx)
                    SliderNames = app.ParamLetters + "Slider";
                    ParamIdx = find(app.SelectedCell(2) == app.ColumnIdx);
                    app.(SliderNames(ParamIdx)).FontWeight = 'bold';
                    ObjectHighlighted(end + 1) = app.(SliderNames(ParamIdx)); 
                end
    
                HighlightSliderTimer = timer(StartDelay = 1, TimerFcn = @(~, ~) set(ObjectHighlighted, ...
                    FontWeight = 'normal'));
                start(HighlightSliderTimer);
    
                function ShiftedIdx = deflectCellSelection
                    if any(app.SelectedCell(2) == [1, 4, 7])
                        IdxShift = +1;
                    elseif any(app.SelectedCell(2) == [3, 6, 9])
                        IdxShift = -1;
                    else
                        IdxShift = 0;
                    end
                    ShiftedIdx = app.SelectedCell(2) + IdxShift;
                end
        end

        % Callback function: not associated with a component
        function ResetAppPushed(app, event)
                cla(app.MainUIAxes);
                hold(app.MainUIAxes, 'off');
    
                app.InterpCheckBox.Value        = false;
                app.InterpPointsSpinner.Enable  = 'off';
                app.InterpolatedData            = [];
                app.NumSimPoints = 60;
                app.InterpPointsSpinner.Value = 60;
                app.ConfidenceIntervals = struct();
    
                % Clear stale handles
                app.SimHndl = [];
                app.ScatterDataHndl = [];
    
                % Clear simulated results
                app.SimulatedR = [];
                app.SimulatedTheta = [];
    
                % Clear table and layers
                app.Layers = cell(10, 9);
                app.LayerParamUITable.Data = cell(app.NumOfLayersSpinner.Value, 9);
    
                app.AngleRangeCheckBox.Value  = false;
                
                app.AngleMinEditField.Visible = 'off';
                app.AngleMaxEditField.Visible = 'off';
    
                % Keep loaded data intact, only re-plot it
                if app.IsDataLoaded
                    app.ScatterDataHndl = plotExperimentalData(app);
                end
    
                startupFcn(app);
        end

        % Value changed function: AutofitSlider
        function AutofitSliderValueChanged(app, event)
                value = app.AutofitSlider.Value;
        end

        % Display data changed function: LayerParamUITable
        function LayerParamUITableDisplayDataChanged(app, event)
          
                
        end

        % Value changed function: nkdvalueTextArea
        function nkdvalueTextAreaValueChanged(app, event)
                value = app.nkdvalueTextArea.Value;
                
        end

        % Callback function: ResetTool
        function ResetToolClicked(app, event)
                app.InterpCheckBox.Value        = false;
                app.InterpPointsSpinner.Enable  = 'off';
                app.InterpolatedData            = [];
                app.NumSimPoints = 60;
                app.InterpPointsSpinner.Value = 60;
                app.ConfidenceIntervals = struct();
    
                % Reset nkd text area
                app.nkdvalueTextArea.Value = {''};
    
                % Reset results table
                app.ResultsTable.Data = {};
    
                % Clear simulated results
                app.SimulatedR     = [];
                app.SimulatedTheta = [];
    
                % Reset nkd label
                app.nkdvalueTextAreaLabel.Text = 'n, k, d value';
    
                % Clear axes
                cla(app.MainUIAxes);
                hold(app.MainUIAxes, 'off');
    
                % Clear graphic handles
                app.SimHndl = [];
                app.ScatterDataHndl = [];
    
                % Clear table and layers
                app.Layers = cell(10, 9);
                app.LayerParamUITable.Data = cell(app.NumOfLayersSpinner.Value, 9);
    
            
                % Clear data
                app.ExperimentalData = [];
                app.IsDataLoaded = false;
                app.LoadDataTextArea.Value = '';
                app.LoaddataButton.Text = 'Load data';
                app.AutoButton.Enable = 'off';
    
                % Reset metric card values
                app.RMSEValueLabel.Text  = '';
                app.R2ValueLabel.Text    = '';
                app.ThetaValueLabel.Text = '';
                app.FWHMValueLabel.Text  = '';
    
                % Reset metric card colors
                for lbl = [app.RMSEValueLabel, app.R2ValueLabel, app.ThetaValueLabel, app.FWHMValueLabel]
                    lbl.FontColor = [0.54 0.54 0.52];
                end
    
                for p = {app.RMSEPanel, app.R2Panel, app.ThetaPanel, app.FWHMPanel}
                    p{1}.BackgroundColor = [0.96 0.96 0.94];
                    p{1}.HighlightColor  = [0.78 0.78 0.76];
                end
    
                % Restart app
                startupFcn(app);
        end

        % Callback function: ExportTool
        function ExportToolClicked(app, event)
            % Prompt user for save location
            DatePrefix  = datestr(now, 'yyyymmdd_HHMMSS');
            DefaultName = sprintf('%s_SPR_Results.xlsx', DatePrefix);
            [FileName, FilePath] = uiputfile('*.xlsx', 'Save results as Excel file', DefaultName);
            if isequal(FileName,0) || isequal(FilePath,0)
                updateStatus(app, 'Export cancelled by user.');
                return;
            end
            FullPath = fullfile(FilePath, FileName);

            % --- Build Experimental Data ---
            ExpData = app.ExperimentalData;
            ExpData = ExpData(ExpData(:,1) <= 60, :);   % clip to <= 60°

            % --- Build Simulated Data ---
            if ~isempty(app.SmoothSimTheta) && ~isempty(app.SmoothSimR)
                SimThetaSource = app.SmoothSimTheta(:);
                SimRSource     = app.SmoothSimR(:);
            elseif ~isempty(app.SimulatedTheta) && ~isempty(app.SimulatedR)
                SimThetaSource = app.SimulatedTheta(:);
                SimRSource     = app.SimulatedR(:);
            else
                SimThetaSource = [];
                SimRSource     = [];
            end

            if ~isempty(SimThetaSource)
                exportStart = SimThetaSource(1);
                exportEnd   = SimThetaSource(end);
            else
                exportStart = app.AngleMin;
                exportEnd   = app.AngleMax;
            end

            angleSpan = max(0, exportEnd - exportStart);
            if app.PointsPerDegree.Value > 0
                ExportNumPts = max(2, ceil(angleSpan * app.PointsPerDegree.Value));
            else
                ExportNumPts = 500;
            end

            ExportThetaVec = linspace(exportStart, exportEnd, ExportNumPts)';
            if ~isempty(SimThetaSource)
                ExportR = interp1(SimThetaSource, SimRSource, ExportThetaVec, 'spline', NaN);
            else
                ExportR = NaN(size(ExportThetaVec));
            end

            % --- Write headers side by side ---
            writecell({'Experimental Angle','Experimental Reflectance', ...
                'Simulated Angle','Simulated Reflectance', ...
                'RMSE','R2','θres (°)','FWHM (°)'}, ...
                FullPath, Sheet='Results', Range='A1');

            % --- Align rows ---
            NumRows = max(size(ExpData,1), numel(ExportThetaVec));
            for i = 1:NumRows
                % Experimental
                if i <= size(ExpData,1)
                    expAngle = ExpData(i,1);
                    expR     = ExpData(i,2);
                else
                    expAngle = NaN; expR = NaN;
                end

                % Simulated
                if i <= numel(ExportThetaVec)
                    simAngle = ExportThetaVec(i);
                    simR     = ExportR(i);
                else
                    simAngle = NaN; simR = NaN;
                end

                % Parameters (only first row, in columns E–H)
                if i == 1
                    rmseVal   = str2double(app.RMSEValueLabel.Text);
                    r2Val     = str2double(app.R2ValueLabel.Text);
                    thetaRes  = str2double(app.ThetaValueLabel.Text);   % use your actual label name
                    fwhmVal   = str2double(app.FWHMValueLabel.Text);       % use your actual label name
                    rowData = {expAngle, expR, simAngle, simR, rmseVal, r2Val, thetaRes, fwhmVal};
                else
                    rowData = {expAngle, expR, simAngle, simR, '', '', '', ''};
                end

                writecell(rowData, FullPath, Sheet='Results', Range=sprintf('A%d', i+1));
            end

            % --- Export all layer parameters further to the right ---
            LayerRow = 2; % start at row 2, columns J–L
            writecell({'Layer','n','k','d (nm)'}, FullPath, Sheet='Results', Range='J1');
            for L = 1:size(app.UsedLayers,1)
                nVal = app.UsedLayers{L,2};
                kVal = app.UsedLayers{L,5};
                dVal = app.UsedLayers{L,8};
                rowData = {L, nVal, kVal, dVal};
                writecell(rowData, FullPath, Sheet='Results', Range=sprintf('J%d', LayerRow+L-1));
            end

            updateStatus(app, 'Export completed successfully.');
        end

        % Callback function: CopyGraph
        function CopyGraphClicked(app, event)
                % Create a temporary invisible figure
                TempFig = figure('Visible', 'off', 'Color', 'white');
                TempFig.Position = [100, 100, 800, 600];
    
                % Create new axes in temp figure
                TempAxes = axes(TempFig);
    
                % Copy all children (plots) from MainUIAxes to TempAxes
                TempChildren = copyobj(app.MainUIAxes.Children, TempAxes);
    
                % Copy axis labels and limits
                TempAxes.XLim   = app.MainUIAxes.XLim;
                TempAxes.YLim   = app.MainUIAxes.YLim;
                TempAxes.XLabel.String = app.MainUIAxes.XLabel.String;
                TempAxes.YLabel.String = app.MainUIAxes.YLabel.String;
                TempAxes.Title.String  = app.MainUIAxes.Title.String;
                TempAxes.Box    = app.MainUIAxes.Box;
                TempAxes.XGrid  = app.MainUIAxes.XGrid;
                TempAxes.YGrid  = app.MainUIAxes.YGrid;
    
                % Copy to clipboard
                print(TempFig, '-clipboard', '-dbitmap');
    
                % Clean up
                close(TempFig);
    
                updateStatus(app, 'Graph copied to clipboard. You can now paste it anywhere.');
        end

        % Value changed function: PrismAngleEditField
        function PrismAngleEditFieldValueChanged(app, event)
               app.PrismDegree = app.PrismAngleEditField.Value;
                if app.IsDataLoaded
                    updateStatus(app, '(!) Prism angle changed — please reload data to reapply angle conversion.');
                end
        end

        % Value changed function: PrismTypeDropDown
        function PrismTypeDropDownValueChanged(app, event)
                 PrismN = struct('SF11', 1.77861, 'BK7', 1.51509, 'SF10', 1.72349, 'LaSFN9', 1.84476);
                SelectedPrism = app.PrismTypeDropDown.Value;
                n_val = PrismN.(SelectedPrism);
    
                % Auto-fill Layer 1 n value
                app.Layers{1, 2} = n_val;
    
                % Only update table if data is already populated
                if ~isempty(app.LayerParamUITable.Data)
                    app.LayerParamUITable.Data{1, 2} = n_val;
                end
    
                % Update UsedLayers
                app.UsedLayers = app.Layers(1:app.NumOfLayersSpinner.Value, :);
    
                % Update slider safely
                if n_val <= app.nSlider.Limits(2)
                    app.nSlider.Value = n_val;
                else
                    app.nSlider.Limits(2) = n_val + 0.5;
                    app.nSlider.Value = n_val;
                end
    
                if app.IsDataLoaded
                    updateStatus(app, sprintf('Prism changed to %s (n=%.5f) — please reload data.', SelectedPrism, n_val));
                else
                    updateStatus(app, sprintf('Prism set to %s (n=%.5f)', SelectedPrism, n_val));
                end
            
                        
        end

        % Callback function: SaveSession
        function SaveSessionClicked(app, event)
                    DatePrefix = datestr(now, 'yyyymmdd_HHMMSS');
                DefaultName = sprintf('%s_SPR_Session.mat', DatePrefix);
                [FileName, FilePath] = uiputfile('*.mat', 'Save session', DefaultName);
                figure(app.UIFigure);
                if isequal(FileName, 0), return; end
                FullPath = fullfile(FilePath, FileName);
    
                Session.Layers           = app.Layers;
                Session.NumOfLayers      = app.NumOfLayersSpinner.Value;
                Session.Lambda           = app.Lambda;
                Session.PrismDegree      = app.PrismDegree;
                Session.PrismType        = app.PrismTypeDropDown.Value;
                Session.ExperimentalData = app.ExperimentalData;
                Session.RawThetaRange    = app.RawThetaRange;
                Session.ThetaRange       = app.ThetaRange;
                Session.SimulatedR       = app.SimulatedR;
                Session.SimulatedTheta   = app.SimulatedTheta;
                Session.IsDataLoaded     = app.IsDataLoaded;
                Session.DataPath         = app.DataPath;
                Session.LoadDataText     = app.LoadDataTextArea.Value;
                
                Session.nkdText          = app.nkdvalueTextArea.Value;
                Session.AutofitSlider    = app.AutofitSlider.Value;
                Session.FittingMode      = app.FittingMode;
                Session.AppVersion       = app.AppVersion;
                Session.AngleMin             = app.AngleMin;
                Session.AngleMax             = app.AngleMax;
                Session.AngleRangeCheckBox   = app.AngleRangeCheckBox.Value;
                Session.InterpCheckBox  = app.InterpCheckBox.Value;
                Session.InterpPoints    = app.InterpPointsSpinner.Value;
                Session.InterpolatedData = app.InterpolatedData;
                Session.ConfidenceIntervals = app.ConfidenceIntervals;
    
    
                save(FullPath, 'Session');
                updateStatus(app, sprintf('Session saved to %s', FileName));
        end

        % Callback function: LoadSession
        function LoadSessionClicked(app, event)
                [FileName, FilePath] = uigetfile('*.mat', 'Load session');
                figure(app.UIFigure);
                if isequal(FileName, 0), return; end
                FullPath = fullfile(FilePath, FileName);
    
                try
                    LoadedData = load(FullPath, 'Session');
                    Session = LoadedData.Session;
    
                    % Check version compatibility
                    if ~isfield(Session, 'AppVersion') || ~strcmp(Session.AppVersion, app.AppVersion)
                        Answer = uiconfirm(app.UIFigure, ...
                            'Session was saved with a different app version. Load anyway?', ...
                            'Version Mismatch', ...
                            Options = {'Load', 'Cancel'}, ...
                            DefaultOption = 'Cancel');
                        if strcmp(Answer, 'Cancel'), return; end
                    end
    
                    % Restore all session data
                    app.Layers                       = Session.Layers;
                    app.Lambda                       = Session.Lambda;
                    app.PrismDegree                  = Session.PrismDegree;
                    app.ExperimentalData             = Session.ExperimentalData;
                    app.RawThetaRange                = Session.RawThetaRange;
                    app.ThetaRange                   = Session.ThetaRange;
                    app.SimulatedR                   = Session.SimulatedR;
                    app.SimulatedTheta               = Session.SimulatedTheta;
                    app.IsDataLoaded                 = Session.IsDataLoaded;
                    app.DataPath                     = Session.DataPath;
                   
                    app.nkdvalueTextArea.Value       = Session.nkdText;
                    app.LoadDataTextArea.Value       = Session.LoadDataText;
                    app.AutofitSlider.Value          = Session.AutofitSlider;
                    app.NumOfLayersSpinner.Value     = Session.NumOfLayers;
                    app.LambdaEditField.Value        = app.Lambda / 1e-9;
                    app.PrismAngleEditField.Value    = app.PrismDegree;
                    app.PrismTypeDropDown.Value      = Session.PrismType;
                    app.LayerSelectionDropDown.Items = listLayers(app);
    
                    % Restore fitting mode
                    switch Session.FittingMode
                        case 'Manual'
                            app.ManualButton.Value = true;
                        case 'Auto'
                            app.AutoButton.Value = true;
                        otherwise
                            app.OffButton.Value = true;
                    end
                    FittingModeButtonGroupSelectionChanged(app);
    
                    if isfield(Session, 'InterpCheckBox')
                        app.InterpCheckBox.Value        = Session.InterpCheckBox;
                        app.InterpPointsSpinner.Value   = Session.InterpPoints;
                        app.InterpolatedData            = Session.InterpolatedData;
                        if Session.InterpCheckBox
                            app.InterpPointsSpinner.Enable = 'on';
                        else
                            app.InterpPointsSpinner.Enable = 'off';
                        end
                    end
    
                    if isfield(Session, 'AngleRangeCheckBox')
                        app.AngleRangeCheckBox.Value = Session.AngleRangeCheckBox;
                        if Session.AngleRangeCheckBox
                            app.AngleMin = Session.AngleMin;
                            app.AngleMax = Session.AngleMax;
                            app.AngleMinEditField.Value = app.AngleMin;
                            app.AngleMaxEditField.Value = app.AngleMax;
                            
                            app.AngleMinEditField.Visible = 'on';
                            app.AngleMaxEditField.Visible = 'on';
                        else
                            
                            app.AngleMinEditField.Visible = 'off';
                            app.AngleMaxEditField.Visible = 'off';
                        end
                    end
    
                    if isfield(Session, 'ConfidenceIntervals')
                        app.ConfidenceIntervals = Session.ConfidenceIntervals;
                    end
    
                    % Restore table
                    updateParamTable(app);
    
                    % Restore plot
                    cla(app.MainUIAxes);
                    hold(app.MainUIAxes, 'on');
                    if app.IsDataLoaded
                        app.ScatterDataHndl = plotExperimentalData(app);
                        app.AutoButton.Enable = 'on';
                        app.LoaddataButton.Text = 'Clear data';
                    end
    
                    % Restore simulation curve if available
                    if ~isempty(app.SimulatedR) && ~isempty(app.SimulatedTheta)
                        % Use SimulatedTheta — already filtered/ranged from original session
                        SmoothThetaVec = linspace(app.SimulatedTheta(1), app.SimulatedTheta(end), app.NumSimPoints);
                        SmoothR = spline(app.SimulatedTheta, app.SimulatedR, SmoothThetaVec);
                        app.SimHndl = plot(app.MainUIAxes, SmoothThetaVec, SmoothR, ...
                            'Color', [0.10 0.23 0.36], ...
                            'LineWidth', 1.8, ...
                            'DisplayName', 'TMM Simulation');
    
                        % Restore resonance angle line
                        [~, MinIdx] = min(SmoothR);
                        ResonanceAngle = SmoothThetaVec(MinIdx);
                        ResonanceLineStyle = '--r';
                        ResonanceColor = [1 0 0];
                        if app.IsDarkMode
                            ResonanceLineStyle = '--';
                            ResonanceColor = [1 0.4 0.4];
                        end
                        xline(app.MainUIAxes, ResonanceAngle, ResonanceLineStyle, ...
                            sprintf('\\theta_{res} = %.2f°', ResonanceAngle), ...
                            LineWidth = 1.5, ...
                            LabelVerticalAlignment = 'bottom', ...
                            LabelHorizontalAlignment = 'right', ...
                            Tag = 'ResonanceLine');
                        ResonanceLineObj = findobj(app.MainUIAxes, 'Tag', 'ResonanceLine');
                        if ~isempty(ResonanceLineObj)
                            ResonanceLineObj.Color      = ResonanceColor;
                            ResonanceLineObj.LabelColor = ResonanceColor;
                        end
    
                        % Restore FWHM line
                        HalfMax = (min(SmoothR) + max(SmoothR)) / 2;
                        AboveHalf = SmoothR >= HalfMax;
                        Transitions = diff(AboveHalf);
                        FallingEdge = find(Transitions == -1, 1, 'first');
                        RisingEdge  = find(Transitions == 1,  1, 'last');
                        if ~isempty(FallingEdge) && ~isempty(RisingEdge)
                            FWHM = SmoothThetaVec(RisingEdge) - SmoothThetaVec(FallingEdge);
                            yline(app.MainUIAxes, HalfMax, '--k', ...
                                sprintf('FWHM = %.2f°', FWHM), ...
                                LineWidth = 1, ...
                                LabelVerticalAlignment = 'bottom', ...
                                LabelHorizontalAlignment = 'left', ...
                                Tag = 'FWHMLine');
                        end
    
                        if isfield(Session, 'AngleMin')
                            app.AngleMin = Session.AngleMin;
                            app.AngleMax = Session.AngleMax;
                            app.AngleMinEditField.Value = app.AngleMin;
                            app.AngleMaxEditField.Value = app.AngleMax;
                        end
    
                        updateResultsTable(app);
                    end
    
                    % Restore metric card values
                    if ~isempty(app.SimulatedR) && ~isempty(app.ThetaRange)
                        % Recalculate metrics from loaded data
                        SmoothThetaVec = linspace(app.SimulatedTheta(1), app.SimulatedTheta(end), app.NumSimPoints);
                        SmoothR = spline(app.SimulatedTheta, app.SimulatedR, SmoothThetaVec);
    
                        [~, MinIdx] = min(SmoothR);
                        ResonanceAngle = SmoothThetaVec(MinIdx);
    
                        HalfMax = (min(SmoothR) + max(SmoothR)) / 2;
                        AboveHalf = SmoothR >= HalfMax;
                        Transitions = diff(AboveHalf);
                        FallingEdge = find(Transitions == -1, 1, 'first');
                        RisingEdge  = find(Transitions == 1,  1, 'last');
                        if ~isempty(FallingEdge) && ~isempty(RisingEdge)
                            FWHM = SmoothThetaVec(RisingEdge) - SmoothThetaVec(FallingEdge);
                        else
                            FWHM = NaN;
                        end
    
                        % Match experimental points to SimulatedTheta range
                        FilteredExp = zeros(length(app.SimulatedTheta), 1);
                        for ii = 1 : length(app.SimulatedTheta)
                            [~, MatchIdx] = min(abs(app.ExperimentalData(:,1) - app.SimulatedTheta(ii)));
                            FilteredExp(ii) = app.ExperimentalData(MatchIdx, 2);
                        end
    
                        if length(FilteredExp) == length(app.SimulatedR)
                            Corr = corrcoef(FilteredExp, app.SimulatedR);
                            R2   = Corr(1,2)^2;
                            RMSE = sqrt(sse(FilteredExp, app.SimulatedR) / (length(app.SimulatedR) - 1));
                        else
                            R2   = NaN;
                            RMSE = NaN;
                        end
    
                        % Update value labels
                        app.RMSEValueLabel.Text  = sprintf('%.4f', RMSE);
                        app.R2ValueLabel.Text    = sprintf('%.4f', R2);
                        app.ThetaValueLabel.Text = sprintf('%.2f', ResonanceAngle);
                        app.FWHMValueLabel.Text  = sprintf('%.2f', FWHM);
    
                        % RMSE card color
                        if isnan(RMSE) || RMSE > 0.05
                            app.RMSEPanel.BackgroundColor = [0.99 0.91 0.91];
                            app.RMSEPanel.HighlightColor  = [0.88 0.50 0.50];
                            app.RMSEValueLabel.FontColor  = [0.55 0.13 0.10];
                        elseif RMSE <= 0.02
                            app.RMSEPanel.BackgroundColor = [0.91 0.96 0.93];
                            app.RMSEPanel.HighlightColor  = [0.55 0.78 0.62];
                            app.RMSEValueLabel.FontColor  = [0.16 0.42 0.23];
                        else
                            app.RMSEPanel.BackgroundColor = [0.99 0.95 0.89];
                            app.RMSEPanel.HighlightColor  = [0.91 0.77 0.48];
                            app.RMSEValueLabel.FontColor  = [0.48 0.29 0.00];
                        end
    
                        % R2 card color
                        if isnan(R2) || R2 < 0.85
                            app.R2Panel.BackgroundColor = [0.99 0.91 0.91];
                            app.R2Panel.HighlightColor  = [0.88 0.50 0.50];
                            app.R2ValueLabel.FontColor  = [0.55 0.13 0.10];
                        elseif R2 < 0.95
                            app.R2Panel.BackgroundColor = [0.99 0.95 0.89];
                            app.R2Panel.HighlightColor  = [0.91 0.77 0.48];
                            app.R2ValueLabel.FontColor  = [0.48 0.29 0.00];
                        else
                            app.R2Panel.BackgroundColor = [0.91 0.96 0.93];
                            app.R2Panel.HighlightColor  = [0.55 0.78 0.62];
                            app.R2ValueLabel.FontColor  = [0.16 0.42 0.23];
                        end
    
                        % Theta card color
                        if ResonanceAngle >= 40 && ResonanceAngle <= 70
                            app.ThetaPanel.BackgroundColor = [0.91 0.96 0.93];
                            app.ThetaPanel.HighlightColor  = [0.55 0.78 0.62];
                            app.ThetaValueLabel.FontColor  = [0.16 0.42 0.23];
                        else
                            app.ThetaPanel.BackgroundColor = [0.99 0.91 0.91];
                            app.ThetaPanel.HighlightColor  = [0.88 0.50 0.50];
                            app.ThetaValueLabel.FontColor  = [0.55 0.13 0.10];
                        end
    
                        % FWHM card color
                        if ~isnan(FWHM) && FWHM <= 5
                            app.FWHMPanel.BackgroundColor = [0.91 0.96 0.93];
                            app.FWHMPanel.HighlightColor  = [0.55 0.78 0.62];
                            app.FWHMValueLabel.FontColor  = [0.16 0.42 0.23];
                        elseif ~isnan(FWHM) && FWHM <= 10
                            app.FWHMPanel.BackgroundColor = [0.99 0.95 0.89];
                            app.FWHMPanel.HighlightColor  = [0.91 0.77 0.48];
                            app.FWHMValueLabel.FontColor  = [0.48 0.29 0.00];
                        else
                            app.FWHMPanel.BackgroundColor = [0.99 0.91 0.91];
                            app.FWHMPanel.HighlightColor  = [0.88 0.50 0.50];
                            app.FWHMValueLabel.FontColor  = [0.55 0.13 0.10];
                        end
                    else
                        % No simulation data — reset panels to neutral
                        for lbl = [app.RMSEValueLabel, app.R2ValueLabel, app.ThetaValueLabel, app.FWHMValueLabel]
                            lbl.Text      = '';
                            lbl.FontColor = [0.54 0.54 0.52];
                        end
                        for p = {app.RMSEPanel, app.R2Panel, app.ThetaPanel, app.FWHMPanel}
                            p{1}.BackgroundColor = [0.96 0.96 0.94];
                            p{1}.HighlightColor  = [0.78 0.78 0.76];
                        end
                    end
                end
        end

        % Value changed function: AngleMinEditField
        function AngleMinEditFieldValueChanged(app, event)
               app.AngleMin = app.AngleMinEditField.Value;
                
        end

        % Value changed function: AngleRangeCheckBox
        function AngleRangeCheckBoxValueChanged(app, event)
                if app.AngleRangeCheckBox.Value
                    % Show range fields
                    
                    app.AngleMinEditField.Visible = 'on';
                    app.AngleMaxEditField.Visible = 'on';
    
                    % Auto-populate from loaded data if available
                    if app.IsDataLoaded
                        app.AngleMin = min(app.ExperimentalData(:, 1));
                        app.AngleMax = max(app.ExperimentalData(:, 1));
                        app.AngleMinEditField.Value = app.AngleMin;
                        app.AngleMaxEditField.Value = app.AngleMax;
                    end
                else
                    % Hide range fields
                    
                    app.AngleMinEditField.Visible = 'off';
                    app.AngleMaxEditField.Visible = 'off';
    
                    % Reset to full data range
                    if app.IsDataLoaded
                        app.AngleMin = min(app.ExperimentalData(:, 1));
                        app.AngleMax = max(app.ExperimentalData(:, 1));
                    else
                        app.AngleMin = 30;
                        app.AngleMax = 70;
                    end
                end
        end

        % Value changed function: AngleMaxEditField
        function AngleMaxEditFieldValueChanged(app, event)
                 app.AngleMax = app.AngleMaxEditField.Value;          
        end

        % Value changed function: InterpCheckBox
        function InterpCheckBoxValueChanged(app, event)
            if app.InterpCheckBox.Value
                app.PointsPerDegree.Enable = 'on';   % enable the edit field
            else
                app.PointsPerDegree.Enable = 'off';  % disable the edit field
            end

            % --- Determine export theta vector ---
            if app.AngleRangeCheckBox.Value
                exportStart = app.AngleMin;
                exportEnd   = app.AngleMax;
            else
                % fallback: use experimental or interpolated data
                if app.InterpCheckBox.Value && ~isempty(app.InterpolatedData)
                    ThetaSource = app.InterpolatedData(:,1);
                elseif ~isempty(app.ExperimentalData)
                    ThetaSource = app.ExperimentalData(:,1);
                elseif ~isempty(app.RawThetaRange)
                    ThetaSource = app.RawThetaRange(:);
                else
                    ThetaSource = linspace(app.AngleMin, app.AngleMax, 100)'; % safe default
                end

                exportStart = ThetaSource(1);
                exportEnd   = ThetaSource(end);
            end

            % Compute angular span
            angleSpan = max(0.0, exportEnd - exportStart);

            % --- Decide number of points ---
            if app.InterpCheckBox.Value
                % Only use PointsPerDegree when interpolation is enabled
                if app.PointsPerDegree.Value > 0
                    ExportNumPts = max(2, ceil(angleSpan * app.PointsPerDegree.Value));
                else
                    ExportNumPts = 500; % fallback default
                end
            else
                % If interpolation is not checked, just use the source resolution
                ExportNumPts = numel(ThetaSource);
            end

            % Build export theta vector
            ExportThetaVec = linspace(exportStart, exportEnd, ExportNumPts)';

            % Sample the smooth curve if available
            if ~isempty(app.SmoothSimTheta) && ~isempty(app.SmoothSimR)
                ExportR = interp1(app.SmoothSimTheta(:), app.SmoothSimR(:), ExportThetaVec, 'spline', NaN);
            else
                ExportR = NaN(size(ExportThetaVec));
            end

                     
        end

        % Value changed function: InterpPointsSpinner
        function InterpPointsSpinnerValueChanged(app, event)
                app.NumSimPoints = app.InterpPointsSpinner.Value;
                if app.InterpCheckBox.Value && app.IsDataLoaded
                    applyInterpolation(app);
                end        
        end

        % Callback function: DarkModeTool
        function DarkModeToolClicked(app, event)
                app.IsDarkMode = ~app.IsDarkMode;
                applyTheme(app);
        end

        % Value changed function: PointsPerDegree
        function PointsPerDegreeValueChanged(app, event)
 app.PointsPerDegreeNumeric = app.PointsPerDegree.Value;
        end

        % Menu selected function: AboutPRISMAMenu
        function AboutPRISMAMenuSelected(app, event)
            % 1. Create a modal popup figure
            AboutFig = uifigure('Name', 'About PRISMA', ...
                'Position', [500, 400, 350, 250], ...
                'WindowStyle', 'modal', ...
                'Color', app.UIFigure.Color); % Match the app's current theme

            % 2. Add App Title
            uilabel(AboutFig, ...
                'Text', 'PRISMA', ...
                'Position', [0, 180, 350, 40], ...
                'HorizontalAlignment', 'center', ...
                'FontSize', 24, ...
                'FontWeight', 'bold', ...
                'FontColor', [0.6, 0.0, 0.0]); % Match your UI accent color

            % 3. Add Full Name & Version
            uilabel(AboutFig, ...
                'Text', sprintf('%s\nVersion %s', app.AppFullName, app.AppVersion), ...
                'Position', [20, 130, 310, 40], ...
                'HorizontalAlignment', 'center', ...
                'WordWrap', 'on', ...
                'FontSize', 12, ...
            'FontColor', [0.6, 0.0, 0.0]); % Added Dark Red

            % 4. Add Author Information
            uilabel(AboutFig, ...
                'Text', 'Developed by: Yusof MKA, Fen YW,', ...
                'Position', [20, 100, 310, 22], ...
                'HorizontalAlignment', 'center', ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
                'FontColor', [0.6, 0.0, 0.0]); % Added Dark Red

            uilabel(AboutFig, ...
                'Text', 'Hamid H, Daniyal WMEMWM, Anuar MF', ...
                'Position', [20, 80, 310, 22], ...
                'HorizontalAlignment', 'center', ...
                'FontSize', 12, ...
                'FontWeight', 'bold', ...
            'FontColor', [0.6, 0.0, 0.0]); % Added Dark Red

            % 5. Add Copyright / License Info
            uilabel(AboutFig, ...
                'Text', char(169) + " 2023 AOLAB & CESS Lab, UPM. All rights reserved.", ... % char(169) is the Copyright symbol
                'Position', [20, 60, 310, 22], ...
                'HorizontalAlignment', 'center', ...
                'FontSize', 10, ...
                'FontColor', [0.6, 0.0, 0.0]); % Added Dark Red

            % 6. Add an "OK" Button to close the popout
            CloseBtn = uibutton(AboutFig, 'push', ...
                'Text', 'OK', ...
                'Position', [125, 15, 100, 30], ...
                'ButtonPushedFcn', @(~,~) delete(AboutFig)); % Destroys the popup when clicked
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 1. Create the confirmation dialog
            selection = uiconfirm(app.UIFigure, ...
                'Are you sure you want to exit PRISMA? Any unsaved data will be lost.', ...
                'Confirm Exit', ...
                'Icon', 'warning', ...
                'Options', {'Yes, Close', 'Cancel'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            % 2. Check the user's selection
            if strcmp(selection, 'Yes, Close')
                % If they confirmed, delete the app to close it completely
                delete(app);
            end
            % If they click 'Cancel' or close the dialog box, the 'if' statement
            % is skipped, and the app simply stays open
            
        end

        % Menu selected function: Changelog
        function ChangelogMenuSelected(app, event)
            % 1. Create a modal popup figure for the Changelog
            ChangelogFig = uifigure('Name', 'Changelog', ...
                'Position', [500, 400, 300, 250], ...
                'WindowStyle', 'modal', ...
                'Color', app.UIFigure.Color); % Match the app's current theme

            % 2. Define the changelog content
            changelogMessage = sprintf('v1.2\n\n- Added dialog before closing apps\n- Added references tab\n- Adjusted n slider\n- Changed font colour for "About PRISMA" tab\n -Remove angle limitation on simulated data\n -Load data always return to location of last file selected');

            % 3. Add the Label with a SMALLER font size
            uilabel(ChangelogFig, ...
                'Text', changelogMessage, ...
                'Position', [20, 50, 260, 170], ...
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'top', ...
                'WordWrap', 'on', ...
                'FontSize', 10); % <--- Set to smaller font (Default is 12)

            % 4. Add an "OK" Button to close the popout
            uibutton(ChangelogFig, 'push', ...
                'Text', 'OK', ...
                'Position', [100, 15, 100, 30], ...
                'ButtonPushedFcn', @(~,~) delete(ChangelogFig)); % Destroys the popup when clicked
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {742, 742};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {488, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PutraSPR';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'AppLogo.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.AboutPRISMAMenu = uimenu(app.Menu);
            app.AboutPRISMAMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutPRISMAMenuSelected, true);
            app.AboutPRISMAMenu.Accelerator = 'i';
            app.AboutPRISMAMenu.Text = 'About PRISMA';
            app.Changelog = uimenu(app.UIFigure);
            app.Changelog.MenuSelectedFcn = createCallbackFcn(app, @ChangelogMenuSelected, true);
            app.Changelog.Text = 'Changelog';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.SaveSession = uipushtool(app.Toolbar);
            app.SaveSession.Tooltip = {'Save Session'};
            app.SaveSession.ClickedCallback = createCallbackFcn(app, @SaveSessionClicked, true);
            app.SaveSession.Icon = fullfile(pathToMLAPP, 'save.png');
            app.LoadSession = uipushtool(app.Toolbar);
            app.LoadSession.Tooltip = {'Load Session'};
            app.LoadSession.ClickedCallback = createCallbackFcn(app, @LoadSessionClicked, true);
            app.LoadSession.Icon = fullfile(pathToMLAPP, 'open.png');
            app.ExportTool = uipushtool(app.Toolbar);
            app.ExportTool.Tooltip = {'Export data'};
            app.ExportTool.ClickedCallback = createCallbackFcn(app, @ExportToolClicked, true);
            app.ExportTool.Icon = fullfile(pathToMLAPP, 'export.png');
            app.CopyGraph = uitoggletool(app.Toolbar);
            app.CopyGraph.Tooltip = {'Copy the graph'};
            app.CopyGraph.ClickedCallback = createCallbackFcn(app, @CopyGraphClicked, true);
            app.CopyGraph.Icon = fullfile(pathToMLAPP, 'copy.png');
            app.DarkModeTool = uipushtool(app.Toolbar);
            app.DarkModeTool.Tooltip = {'Toogle Dark Mode'};
            app.DarkModeTool.ClickedCallback = createCallbackFcn(app, @DarkModeToolClicked, true);
            app.DarkModeTool.Icon = fullfile(pathToMLAPP, '5262027.png');
            app.ResetTool = uipushtool(app.Toolbar);
            app.ResetTool.Tooltip = {'Reset Software'};
            app.ResetTool.ClickedCallback = createCallbackFcn(app, @ResetToolClicked, true);
            app.ResetTool.Icon = fullfile(pathToMLAPP, 'reset.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SetupPanel = uipanel(app.GridLayout2);
            app.SetupPanel.Title = 'Setup';
            app.NooflayersSpinnerLabel = uilabel(app.SetupPanel);
            app.NooflayersSpinnerLabel.Text = 'No. of layers';
            app.NumOfLayersSpinner = uispinner(app.SetupPanel);
            app.NumOfLayersSpinner.Limits = [1 10];
            app.NumOfLayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumOfLayersSpinnerValueChanged, true);
            app.NumOfLayersSpinner.Value = 3;
            app.LoaddataButton = uibutton(app.SetupPanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.LoadDataTextArea = uitextarea(app.SetupPanel);
            app.LoadDataTextArea.Editable = 'off';
            app.nmLabel = uilabel(app.SetupPanel);
            app.nmLabel.Text = '𝝀 (nm)';
            app.LambdaEditField = uieditfield(app.SetupPanel, 'numeric');
            app.LambdaEditField.ValueChangedFcn = createCallbackFcn(app, @LambdaEditFieldValueChanged, true);
            app.PrismtypeDropDownLabel = uilabel(app.SetupPanel);
            app.PrismtypeDropDownLabel.Text = 'Prism type';
            app.PrismTypeDropDown = uidropdown(app.SetupPanel);
            app.PrismTypeDropDown.Items = {'SF11', 'SF10', 'BK7', 'LaSFN9'};
            app.PrismTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PrismTypeDropDownValueChanged, true);
            app.PrismTypeDropDown.Value = 'SF11';
            app.PrismAngleEditFieldLabel = uilabel(app.SetupPanel);
            app.PrismAngleEditFieldLabel.Text = 'Prism Angle (°)';
            app.PrismAngleEditField = uieditfield(app.SetupPanel, 'numeric');
            app.PrismAngleEditField.ValueChangedFcn = createCallbackFcn(app, @PrismAngleEditFieldValueChanged, true);
            app.StatusTextArea = uitextarea(app.GridLayout2);
            app.StatusTextArea.Editable = 'off';
            app.SimulationPanel = uipanel(app.GridLayout2);
            app.SimulationPanel.Title = 'Simulation';
            app.LayerParamUITable = uitable(app.SimulationPanel);
            app.LayerParamUITable.ColumnName = {''};
            app.LayerParamUITable.RowName = {};
            app.LayerParamUITable.ColumnEditable = true;
            app.LayerParamUITable.CellEditCallback = createCallbackFcn(app, @LayerParamUITableCellEdit, true);
            app.LayerParamUITable.DisplayDataChangedFcn = createCallbackFcn(app, @LayerParamUITableDisplayDataChanged, true);
            app.LayerParamUITable.SelectionChangedFcn = createCallbackFcn(app, @LayerParamUITableSelectionChanged, true);
            app.LayerParamUITable.Multiselect = 'off';
            app.nLeftButton = uibutton(app.SimulationPanel, 'push');
            app.nLeftButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.nLeftButton.Tag = 'nl';
            app.nLeftButton.Text = '◀';
            app.nRightButton = uibutton(app.SimulationPanel, 'push');
            app.nRightButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.nRightButton.Tag = 'nr';
            app.nRightButton.Text = '▶';
            app.AutofitLabel = uilabel(app.SimulationPanel);
            app.AutofitLabel.Text = 'Auto-fitting';
            app.AutofitSlider = uislider(app.SimulationPanel);
            app.AutofitSlider.Limits = [5 400];
            app.AutofitSlider.MajorTicks = [0 10 50 100 200 300 400 500];
            app.AutofitSlider.MajorTickLabels = {'0', '10', '50', '100', '200', '300', '400', '500'};
            app.AutofitSlider.ValueChangedFcn = createCallbackFcn(app, @AutofitSliderValueChanged, true);
            app.AutofitSlider.ValueChangingFcn = createCallbackFcn(app, @AutofitSliderValueChanging, true);
            app.AutofitSlider.MinorTicks = [5 16 27 38 49 60 71 82 93 104 115 126 137 148 159 170 181 192 203 214 225 236 247 258 269 280 291 302 313 324 335 346 357 368 379 390 401 412 423 434 445 456 467 478 489 500];
            app.AutofitSlider.Tooltip = {'Coarser tuning is less precise, but faster, and vice versa.'};
            app.AutofitSlider.Value = 50;
            app.ButtonTuningLabel = uilabel(app.SimulationPanel);
            app.ButtonTuningLabel.Text = 'Button tuning';
            app.ButtonTuningSlider = uislider(app.SimulationPanel);
            app.ButtonTuningSlider.Limits = [1 5];
            app.ButtonTuningSlider.MajorTicks = [1 2 3 4 5];
            app.ButtonTuningSlider.ValueChangedFcn = createCallbackFcn(app, @ButtonTuningSliderValueChanged, true);
            app.ButtonTuningSlider.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5];
            app.ButtonTuningSlider.Value = 3;
            app.LayerSelectionDropDown = uidropdown(app.SimulationPanel);
            app.LayerSelectionDropDown.Items = {'Layer 1', 'Layer 2'};
            app.LayerSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerSelectionDropDownValueChanged, true);
            app.LayerSelectionDropDown.Value = 'Layer 1';
            app.SliderLabel = uilabel(app.SimulationPanel);
            app.SliderLabel.Text = '𝑛';
            app.nSlider = uislider(app.SimulationPanel);
            app.nSlider.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.nSlider.ValueChangingFcn = createCallbackFcn(app, @nSliderValueChanging, true);
            app.kLeftButton = uibutton(app.SimulationPanel, 'push');
            app.kLeftButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.kLeftButton.Tag = 'kl';
            app.kLeftButton.Text = '◀';
            app.kRightButton = uibutton(app.SimulationPanel, 'push');
            app.kRightButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.kRightButton.Tag = 'kr';
            app.kRightButton.Text = '▶';
            app.kLabel = uilabel(app.SimulationPanel);
            app.kLabel.Text = 'k';
            app.kSlider = uislider(app.SimulationPanel);
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @kSliderValueChanged, true);
            app.kSlider.ValueChangingFcn = createCallbackFcn(app, @kSliderValueChanging, true);
            app.dLeftButton = uibutton(app.SimulationPanel, 'push');
            app.dLeftButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.dLeftButton.Tag = 'dl';
            app.dLeftButton.Text = '◀';
            app.dRightButton = uibutton(app.SimulationPanel, 'push');
            app.dRightButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.dRightButton.Tag = 'dr';
            app.dRightButton.Text = '▶';
            app.nmSliderLabel = uilabel(app.SimulationPanel);
            app.nmSliderLabel.Text = '𝑑 (nm)';
            app.dSlider = uislider(app.SimulationPanel);
            app.dSlider.ValueChangedFcn = createCallbackFcn(app, @dSliderValueChanged, true);
            app.dSlider.ValueChangingFcn = createCallbackFcn(app, @dSliderValueChanging, true);
            app.AutofitRunButton = uibutton(app.SimulationPanel, 'push');
            app.AutofitRunButton.ButtonPushedFcn = createCallbackFcn(app, @AutofitRunButtonPushed, true);
            app.AutofitRunButton.Text = 'Run Auto-Fit';
            app.AutofitValueLabel = uilabel(app.SimulationPanel);
            app.AutofitValueLabel.Text = '';
            app.ResetRangesButton = uibutton(app.SimulationPanel, 'push');
            app.ResetRangesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRangesButtonPushed, true);
            app.ResetRangesButton.Text = 'Reset ranges';
            app.FittingModeButtonGroup = uibuttongroup(app.SimulationPanel);
            app.FittingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FittingModeButtonGroupSelectionChanged, true);
            app.OffButton = uiradiobutton(app.FittingModeButtonGroup);
            app.OffButton.Text = 'Off';
            app.ManualButton = uiradiobutton(app.FittingModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutoButton = uiradiobutton(app.FittingModeButtonGroup);
            app.AutoButton.Tooltip = {'Enter lower and upper limit for acquired parameters'};
            app.AutoButton.Text = 'Auto';
            app.FittingModeLabel = uilabel(app.SimulationPanel);
            app.FittingModeLabel.Text = 'Fitting mode';
            app.CoarserLabel = uilabel(app.SimulationPanel);
            app.CoarserLabel.Text = 'Coarser';
            app.FinerLabel = uilabel(app.SimulationPanel);
            app.FinerLabel.Text = 'Finer';
            app.CoarserLabel_2 = uilabel(app.SimulationPanel);
            app.CoarserLabel_2.Text = 'Coarser';
            app.FinerLabel_3 = uilabel(app.SimulationPanel);
            app.FinerLabel_3.Text = 'Finer';
            app.Fornkandd1LowerLimit2UpperLimitLabel = uilabel(app.SimulationPanel);
            app.Fornkandd1LowerLimit2UpperLimitLabel.Text = {'For n,k and d'; '1 = Lower Limit'; '2 = Upper Limit'};
            app.AngleMinEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.AngleMinEditField.Limits = [-Inf 70];
            app.AngleMinEditField.ValueChangedFcn = createCallbackFcn(app, @AngleMinEditFieldValueChanged, true);
            app.AngleMinEditField.Value = 50;
            app.AngleRangeCheckBox = uicheckbox(app.SimulationPanel);
            app.AngleRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngleRangeCheckBoxValueChanged, true);
            app.AngleRangeCheckBox.Text = 'Specify angle range';
            app.AngleMaxEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.AngleMaxEditField.Limits = [40 Inf];
            app.AngleMaxEditField.ValueChangedFcn = createCallbackFcn(app, @AngleMaxEditFieldValueChanged, true);
            app.AngleMaxEditField.Value = 58;
            app.Label = uilabel(app.SimulationPanel);
            app.Label.Text = '–';
            app.Panel = uipanel(app.GridLayout2);
            app.StatusLamp = uilamp(app.GridLayout2);
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanelGridLayout = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.RightPanelGridLayout);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.MainTabGridLayout = uigridlayout(app.MainTab);
            app.MainUIAxes = uiaxes(app.MainTabGridLayout);
            title(app.MainUIAxes, 'SPR Reflectance Curve')
            xlabel(app.MainUIAxes, 'Angle (°)')
            ylabel(app.MainUIAxes, 'Reflectance')
            colormap(app.MainUIAxes, 'hot')
            app.AppNameLabel = uilabel(app.MainTabGridLayout);
            app.AppNameLabel.Text = '';
            app.InfoPanel = uipanel(app.MainTabGridLayout);
            app.InfoPanel.Title = 'Info';
            app.GridLayout3 = uigridlayout(app.InfoPanel);
            app.PlasmaResonanceIntegratedSoftwarewithModelAutofittingPRISMLabel = uilabel(app.GridLayout3);
            app.PlasmaResonanceIntegratedSoftwarewithModelAutofittingPRISMLabel.Text = 'Plasma Resonance Integrated Software with Model Auto-fitting (PRISM△)';
            app.RMSEPanel = uipanel(app.GridLayout3);
            app.RMSEValueLabel = uilabel(app.RMSEPanel);
            app.RMSEValueLabel.Text = '';
            app.RMSELabel = uilabel(app.RMSEPanel);
            app.RMSELabel.Text = 'RMSE';
            app.R2Panel = uipanel(app.GridLayout3);
            app.R2ValueLabel = uilabel(app.R2Panel);
            app.R2ValueLabel.Text = '';
            app.R2Label = uilabel(app.R2Panel);
            app.R2Label.Text = 'R²';
            app.ThetaPanel = uipanel(app.GridLayout3);
            app.ThetaValueLabel = uilabel(app.ThetaPanel);
            app.ThetaValueLabel.Text = '';
            app.ThetaLabel = uilabel(app.ThetaPanel);
            app.ThetaLabel.Text = 'θ res (°)';
            app.FWHMPanel = uipanel(app.GridLayout3);
            app.FWHMValueLabel = uilabel(app.FWHMPanel);
            app.FWHMValueLabel.Text = '';
            app.FWHMLabel = uilabel(app.FWHMPanel);
            app.FWHMLabel.Text = 'FWHM (°)';
            app.nkdvalueTextAreaLabel = uilabel(app.GridLayout3);
            app.nkdvalueTextAreaLabel.Text = 'n, k, d value';
            app.nkdvalueTextArea = uitextarea(app.GridLayout3);
            app.nkdvalueTextArea.ValueChangedFcn = createCallbackFcn(app, @nkdvalueTextAreaValueChanged, true);
            app.nkdvalueTextArea.Editable = 'off';
            app.RedBadFitLabel = uilabel(app.GridLayout3);
            app.RedBadFitLabel.Text = 'Red =  Bad Fit';
            app.AmberAcceptableFitLabel = uilabel(app.GridLayout3);
            app.AmberAcceptableFitLabel.Text = 'Amber = Acceptable Fit';
            app.GreenGoodFitLabel = uilabel(app.GridLayout3);
            app.GreenGoodFitLabel.Text = 'Green = Good Fit';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.NoOfDataSpinnerLabel = uilabel(app.DataTab);
            app.NoOfDataSpinnerLabel.Enable = 'off';
            app.NoOfDataSpinnerLabel.Visible = 'off';
            app.NoOfDataSpinnerLabel.Text = 'No. Of Data';
            app.InterpPointsSpinner = uispinner(app.DataTab);
            app.InterpPointsSpinner.Limits = [10 5000];
            app.InterpPointsSpinner.ValueDisplayFormat = '%.0f';
            app.InterpPointsSpinner.ValueChangedFcn = createCallbackFcn(app, @InterpPointsSpinnerValueChanged, true);
            app.InterpPointsSpinner.Enable = 'off';
            app.InterpPointsSpinner.Visible = 'off';
            app.InterpPointsSpinner.Value = 60;
            app.InterpCheckBox = uicheckbox(app.DataTab);
            app.InterpCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterpCheckBoxValueChanged, true);
            app.InterpCheckBox.Tooltip = {'Default = 20 simulated point in 1°.'};
            app.InterpCheckBox.Text = 'Adjust Resolution of Simulated Data';
            app.ResultsTable = uitable(app.DataTab);
            app.ResultsTable.ColumnName = {'Angle (deg)'; 'Reflectance (Exp)'; 'Reflectance (Sim)'};
            app.ResultsTable.RowName = {};
            app.ResolutionEditFieldLabel = uilabel(app.DataTab);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.PointsPerDegree = uieditfield(app.DataTab, 'numeric');
            app.PointsPerDegree.Limits = [0 50];
            app.PointsPerDegree.ValueDisplayFormat = '%.0f';
            app.PointsPerDegree.AllowEmpty = 'on';
            app.PointsPerDegree.ValueChangedFcn = createCallbackFcn(app, @PointsPerDegreeValueChanged, true);
            app.PointsPerDegree.Enable = 'off';
            app.PointsPerDegree.Tooltip = {'The adjust resolution only applied on exported data'};
            app.PointsPerDegree.Value = 20;
            app.ReferencesTab = uitab(app.TabGroup);
            app.ReferencesTab.Title = 'References';
            app.TohelpfindthenandkvaluesofmaterialsTextAreaLabel = uilabel(app.ReferencesTab);
            app.TohelpfindthenandkvaluesofmaterialsTextAreaLabel.Text = 'To help find the n and k values of materials';
            app.TohelpfindthenandkvaluesofmaterialsTextArea = uitextarea(app.ReferencesTab);
            app.TohelpfindthenandkvaluesofmaterialsTextArea.Editable = 'off';
            app.TohelpfindthenandkvaluesofmaterialsTextArea.Value = {'https://refractiveindex.info'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPRSoftwarev1p1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end